classdef MitoModel_V1_May_2024 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        file                            matlab.ui.container.Menu
        Menu                            matlab.ui.container.Menu
        SaveGraphsMenu                  matlab.ui.container.Menu
        SaveSessionMenu                 matlab.ui.container.Menu
        LoadPreviousSessionsMenu        matlab.ui.container.Menu
        SaveResultsMenu                 matlab.ui.container.Menu
        LoadPreviousResultsMenu         matlab.ui.container.Menu
        LoadPreviouslyOptimizedParametersMenu  matlab.ui.container.Menu
        ExitMenu                        matlab.ui.container.Menu
        help                            matlab.ui.container.Menu
        AboutMitoModelMenu              matlab.ui.container.Menu
        MitoModelVersionMenu            matlab.ui.container.Menu
        MitoModelInstructionsMenu       matlab.ui.container.Menu
        FeedbackMenu                    matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        MainControlLabel                matlab.ui.control.Label
        ProblemCustomizationLabel       matlab.ui.control.Label
        DataAnalysisHubPanel            matlab.ui.container.Panel
        VisualizationLabel              matlab.ui.control.Label
        VisualizationPanel              matlab.ui.container.Panel
        VisualizationTypeDropDown       matlab.ui.control.DropDown
        ResultsLabel                    matlab.ui.control.Label
        OptimizationLabel               matlab.ui.control.Label
        ResultsPanel                    matlab.ui.container.Panel
        ResultsTypeDropDown             matlab.ui.control.DropDown
        OptimizationPanel               matlab.ui.container.Panel
        OptimizationAlgTypeDropDown     matlab.ui.control.DropDown
        ParameterInitializationLabel    matlab.ui.control.Label
        ParameterInitializationPanel    matlab.ui.container.Panel
        TotalCytCEditField              matlab.ui.control.NumericEditField
        TotalCytCEditFieldLabel         matlab.ui.control.Label
        ResetButton                     matlab.ui.control.Button
        IMSProtonHsubPsubEditField      matlab.ui.control.NumericEditField
        IMSProtonHsubPsubEditFieldLabel  matlab.ui.control.Label
        MatrixProtonHsubNsubEditField   matlab.ui.control.NumericEditField
        MatrixProtonH_nLabel            matlab.ui.control.Label
        OxygenEditField                 matlab.ui.control.NumericEditField
        OxygenEditFieldLabel            matlab.ui.control.Label
        CytCOxidizedEditField           matlab.ui.control.NumericEditField
        CytCOxidizedEditFieldLabel      matlab.ui.control.Label
        Problem_Customization           matlab.ui.container.Panel
        TimeUnitToggle                  matlab.ui.container.ButtonGroup
        MinuteButton                    matlab.ui.control.ToggleButton
        SecondButton                    matlab.ui.control.ToggleButton
        SimulationtimesecEditFieldLabel_2  matlab.ui.control.Label
        ExcelDataLoadButton             matlab.ui.control.Button
        LoadExcelDataDropDown           matlab.ui.control.DropDown
        EditTableButton                 matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        ModelSelectorPanel              matlab.ui.container.Panel
        UIWeightTable                   matlab.ui.control.Table
        ButtonGroupPenalty              matlab.ui.container.ButtonGroup
        WeightedButton                  matlab.ui.control.RadioButton
        UnweightedButton                matlab.ui.control.RadioButton
        ButtonGroupModel                matlab.ui.container.ButtonGroup
        FullModelGlobalButton           matlab.ui.control.RadioButton
        BaselineModelButton             matlab.ui.control.RadioButton
        RightPanel                      matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        GraphsTab                       matlab.ui.container.Tab
        ShowErrorBarCheckBox            matlab.ui.control.CheckBox
        MathematicalModelforMitochondrialFunctioningLabel  matlab.ui.control.Label
        H_P_plot                        matlab.ui.control.UIAxes
        H_N_plot                        matlab.ui.control.UIAxes
        OCR_plot                        matlab.ui.control.UIAxes
        O2_plot                         matlab.ui.control.UIAxes
        Cytc_plot                       matlab.ui.control.UIAxes
        ResultsTab                      matlab.ui.container.Tab
        mcmcInfoPanel                   matlab.ui.container.Panel
        mcmcInfoTable                   matlab.ui.control.Table
        GAInfo                          matlab.ui.container.Panel
        GAInfoTable                     matlab.ui.control.Table
        OptimizedParametersPanel        matlab.ui.container.Panel
        ResultsTable                    matlab.ui.control.Table
        CorrelationAnalysisTab          matlab.ui.container.Tab
        LessthanandequaltoEditField     matlab.ui.control.NumericEditField
        LessthanandequaltoEditFieldLabel  matlab.ui.control.Label
        CorrTabTextbox                  matlab.ui.control.Label
        GreaterthanandequaltoEditField  matlab.ui.control.NumericEditField
        GreaterthanandequaltoEditFieldLabel  matlab.ui.control.Label
        CorrMatrixTable                 matlab.ui.control.Table
        CorrelationMatrixPanel          matlab.ui.container.Panel
        NextButton                      matlab.ui.control.Button
        PreviousButton                  matlab.ui.control.Button
        ErrorAnalysisTab                matlab.ui.container.Tab
        BackButton                      matlab.ui.control.Button
        ParameterselectionPanel         matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        PlotErrorAnalysisButton         matlab.ui.control.Button
        CheckBox_Cyt_c_red_prop         matlab.ui.control.CheckBox
        CheckBox_P_leak                 matlab.ui.control.CheckBox
        Label_16_r_attenuate            matlab.ui.control.Label
        CheckBox_V_Max_C_V              matlab.ui.control.CheckBox
        Label_15_alpha_4                matlab.ui.control.Label
        Label_14_alpha_3                matlab.ui.control.Label
        CheckBox_K_C_V                  matlab.ui.control.CheckBox
        Label_13_alpha_2                matlab.ui.control.Label
        CheckBox_K_m_C_V                matlab.ui.control.CheckBox
        Label_12_alpha_1                matlab.ui.control.Label
        CheckBox_V_Max_C_IV             matlab.ui.control.CheckBox
        Label_11_Cyt_c_red_prop         matlab.ui.control.Label
        CheckBox_alpha_2                matlab.ui.control.CheckBox
        Label_10_P_leak                 matlab.ui.control.Label
        CheckBox_alpha_1                matlab.ui.control.CheckBox
        Label_9_K_m_C_V                 matlab.ui.control.Label
        CheckBox_alpha_3                matlab.ui.control.CheckBox
        Label_8_K_C_V                   matlab.ui.control.Label
        CheckBox_alpha_4                matlab.ui.control.CheckBox
        Label_7_CheckBox_V_Max_C_V      matlab.ui.control.Label
        CheckBox_r_attenuate            matlab.ui.control.CheckBox
        Label_6_K_C_IV                  matlab.ui.control.Label
        CheckBox_K_C_IV                 matlab.ui.control.CheckBox
        Label_5_K_m_C_IV                matlab.ui.control.Label
        Label_4_V_Max_C_IV              matlab.ui.control.Label
        CheckBox_K_m_C_IV               matlab.ui.control.CheckBox
        Label_3_K_m_c0                  matlab.ui.control.Label
        CheckBox_K_m_c0                 matlab.ui.control.CheckBox
        Label_2_Cyt_c_tot               matlab.ui.control.Label
        CheckBox_Cyt_c_tot              matlab.ui.control.CheckBox
        Label_0                         matlab.ui.control.Label
        Label_1_V_Max_C0                matlab.ui.control.Label
        CheckBox_V_Max_C0               matlab.ui.control.CheckBox
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    properties (Access = public)
        parameters = setup_mito; % Loading defual values for the intial parameters
        UIAxes matlab.ui.control.UIAxes
        DataLoaded = false;
        ModelSolved = false;
        ModelOptimized = false;
        PrevResultsLoaded = false;
        PrevParamsLoaded = false;

        OxygenEditFieldStatus = false;
        cytcoxEditFieldStatus = false;
        cytctotEditFieldStatus = false;
        MatrixProtonHsubNsubEditFieldStatus = false
        IMSProtonHsubPsubEditFieldStatus = false;


        params = struct();
        results = struct();
        data = struct();
        Model = struct();
        modeleqns = struct();
        gaInfo = struct();
        MCMCResults % Structure to store MCMC results
        CurrentTimeUnit = 'Second'; % Default to 'Second'
        OptimOutput
        PrevResults = struct();
        PrevParams = struct();


        CorrelationHeatmapUIAxes % Property to store the UIAxes for the heatmap
        prevButton % Property to store the "Previous" button
        nextButton % Property to store the "Next" button
        heatmap
        currentView
        SelectedParameters
        DynamicAxes % Cell array to store dynamically created axes

    end

    properties (Access = private)
        filename
        folder
        TableConverter
        sketchedLines matlab.graphics.chart.primitive.Line
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            close all

            addpath(fullfile(pwd, 'AppFunctions'));
            addpath(fullfile(pwd, 'AppFunctions/SystemFunctions'));
            addpath(fullfile(pwd, 'AppFunctions/OptimizationFunctions'));
            addpath(fullfile(pwd, 'AppFunctions/ResetFunctions'));
            addpath(fullfile(pwd, 'AppFunctions/ResultsFunctions'));

            % Close specific message boxes or dialog boxes by finding their handles
            delete(findall(0, 'Type', 'figure', 'Tag', 'MsgBoxTag')); % Replace 'MsgBoxTag' with actual Tag if you set one
            delete(findall(0, 'Type', 'figure', 'Name', 'Message Box')); % Closes boxes with this specific name


            movegui(app.UIFigure, 'center');
            app.UIFigure.WindowState = 'maximized';
            app.UIFigure.Name = 'MitoModel';
            app.params = app.parameters;
            app.EditTableButton.Enable = 'off';
            app.VisualizationTypeDropDown.Enable = 'off';
            app.OptimizationAlgTypeDropDown.Enable = 'off';
            app.ResultsTypeDropDown.Enable = 'off';
            app.ButtonGroupPenalty.Enable = 'off';
            app.UIWeightTable.Enable = 'off';
            app.FullModelGlobalButton.Enable = 'off';
            app.Model.Type = 'BaselineModel';
            app.Model.PenaltyType = 'Disable Penalty';
            app.Model.Weight = ones(6,1);

            app.OptimizedParametersPanel.Visible = 'off';
            app.mcmcInfoPanel.Visible = 'off';
            app.GAInfo.Visible = 'off';


            app.CorrelationMatrixPanel.Visible = 'off';
            app.CorrTabTextbox.Visible = 'off';
            app.LessthanandequaltoEditField.Visible = 'off';
            app.LessthanandequaltoEditFieldLabel.Visible = 'off';
            app.GreaterthanandequaltoEditField.Visible = 'off';
            app.GreaterthanandequaltoEditFieldLabel.Visible = 'off';
            app.PreviousButton.Enable = 'off';  % Disable the 'Previous' button
            app.NextButton.Enable = 'on';
            app.PreviousButton.Visible = 'off';
            app.NextButton.Visible = 'off';

            % Show the heatmap and hide the matrix table
            app.heatmap.Visible = 'on';    % Make the heatmap visible
            app.currentView = 'heatmap';    % Update current view
            app.CorrelationMatrixPanel.Visible = 'off'; % Hide the panel
            app.CorrMatrixTable.Visible = 'off'; % Hide the table

            app.ParameterselectionPanel.Visible = 'off';
            app.CorrMatrixTable.Visible = 'off';
            app.Label_12_alpha_1.Visible = 'off';
            app.Label_13_alpha_2.Visible = 'off';
            app.Label_14_alpha_3.Visible = 'off';
            app.Label_15_alpha_4.Visible = 'off';
            app.Label_16_r_attenuate.Visible = 'off';
            app.BackButton.Visible = 'off';
            app.ShowErrorBarCheckBox.Visible = 'off';

            % app.ExcelDataLoadButton.Enable = 'off'; The defualt value of
            % the time unit toggle button is second.
            run('setupGA.m')
            %% Define the function handles for the current model system
            app.modeleqns.FullModelGlobal = {@baselineSystem, @oligoSystem, ...
                @fccpSystem, @inhibitSystem};
            app.modeleqns.BaselineModel = {@baselineSystem};
        end

        % Callback function
        function LaodButtonPushed(app, event)
            % Read the Excel data
            app.t = readtable(fullfile(app.folder,app.LoadExcelDataDropDown.Value));

            % Place table data into uitable
            app.UITable.Data = app.t;
            app.UITable.ColumnName = app.t.Properties.VariableNames;
            app.IndependentVariableListBox.Items = app.t.Properties.VariableNames;
            app.DependentVariableListBox.Items = app.t.Properties.VariableNames;
        end

        % Callback function: ExcelDataLoadButton, LoadExcelDataDropDown
        function ExcelDataLoadButtonPushed(app, event)
            resetLoadedData(app)
            % Clear the axes
            clearAxes(app.O2_plot);
            clearAxes(app.OCR_plot);
            clearAxes(app.Cytc_plot);
            clearAxes(app.H_N_plot);
            clearAxes(app.H_P_plot);

            % Get the current directory
            currentDir = pwd;

            % Define the path to the 'Data' subfolder inside the current directory
            dataFolderPath = fullfile(currentDir, 'Data');

            % Open the file selection dialog with the 'Data' subfolder as the starting location
            [app.filename, app.folder] = uigetfile(fullfile(dataFolderPath, '*.xls*'));

            % Check if the user canceled the file selection
            if isequal(app.filename, 0)
                disp('User canceled the file selection');
                return;
            end

            app.TabGroup.SelectedTab = app.GraphsTab;
            % Clear previous results
            if ~isempty(app.ResultsTable.Data)
                app.ResultsTable.Data= {};
            end
            if ~isempty(app.GAInfoTable.Data)
                app.GAInfoTable.Data = {};
            end
            if ~isempty(app.mcmcInfoTable.Data)
                app.GAInfoTable.Data = {};
            end

            app.results = struct();
            app.MCMCResults = struct();
            app.data = struct();
            app.gaInfo = struct();
            app.OptimOutput = struct();

            % Initialize allData to avoid "Unrecognized function or variable" error
            allData = [];

            % Prompt the user to choose the method of data loading
            choice = questdlg('How would you like to load the data?', ...
                'Data Loading Method', ...
                'Specify Range', 'Specify Columns', 'Specify Range');
            switch choice
                case 'Specify Range'
                    % Prompt the user to specify the range and sheet name
                    prompt = {'Enter the range of cells (e.g., A1:B10):', 'Enter the sheet name:'};
                    dlgtitle = 'Specify Range and Sheet Name';
                    dims = [1 50];
                    definput = {'A1:B10', 'Sheet1'}; % Default range and sheet name
                    inputdata = inputdlg(prompt, dlgtitle, dims, definput);

                    % Check if the user canceled the operation
                    if isempty(inputdata)
                        disp('User canceled the operation');
                        return;
                    end

                    range = inputdata{1};
                    sheetname = inputdata{2};

                    % Read data from Excel file
                    if isempty(range)
                        % Read the entire Excel file from the specified sheet
                        [allData, timePoints] = xlsread(fullfile(app.folder, app.filename), sheetname);
                    else
                        % Read data from the specified range and sheet
                        [allData, timePoints] = xlsread(fullfile(app.folder, app.filename), sheetname, range);
                    end

                    % Extract field names from the first row (assuming it contains headers)
                    fieldNames = timePoints(1, :);
                    timePoints(1, :) = [];
                    timePoints(:, 1:end-1) = [];

                case 'Specify Columns'
                    % Prepare input dialog for inserting columns manually
                    prompt = {'Time (s):', 'Oxygen Concentration [nmol/ml]:', ...
                        'OCR [pmol/(s*ml)] (optional):', 'Standard deviation of O2 (optional):', ...
                        'Time Points:', 'Enter the sheet name:'};
                    dlgtitle = 'Insert Columns';
                    dims = [1 50];
                    definput = {'A1:B10', 'A1:B10', '', '', 'A1:B10', 'Sheet1'}; % Default inputs are empty

                    % Display the dialog box
                    inputdata = inputdlg(prompt, dlgtitle, dims, definput);

                    % Check if the user canceled the operation or left inputs empty
                    if isempty(inputdata)
                        disp('User canceled the operation');
                        return;
                    end

                    range1 = inputdata{1};
                    range2 = inputdata{2};
                    range3 = inputdata{3};
                    range4 = inputdata{4};
                    range5 = inputdata{5};
                    sheetname = inputdata{6};


                    % Read Time data from Excel file
                    if isempty(range1)
                        waitfor(msgbox( 'The column you have inserted for time does not include any entry. Please try to insert this column again.'));
                        return
                    else
                        % Read data from the specified range and sheet
                        [allData1, timePoints1] = xlsread(fullfile(app.folder, app.filename), sheetname, range1);
                    end

                    % Read Oxygen Concentration data from Excel file
                    if isempty(range2)
                        waitfor(msgbox( 'The column you have inserted for Oxygen Concentration does not include any entry. Please try to insert this column again.'));
                        return
                    else
                        % Read data from the specified range and sheet
                        [allData2, timePoints2] = xlsread(fullfile(app.folder, app.filename), sheetname, range2);
                    end
                    % Read OCR data from Excel file
                    if isempty(range3)
                        msgbox( 'The column you have inserted for OCR does not include any entry, so the OCR will be calculated automatically.');
                        allData3 = nan(size(allData1));
                        timePoints3 = ['AverageOCR'; nan(size(allData1)-1)];

                    else
                        % Read data from the specified range and sheet
                        [allData3, timePoints3] = xlsread(fullfile(app.folder, app.filename), sheetname, range3);
                    end
                    % Read Error data from Excel file
                    if isempty(range4)
                        msgbox( 'The column you have inserted for Error does not include any entry. So the error will be calculated automatically if needed.');
                        allData4 = nan(size(allData1));
                        timePoints4 = ['STDEV'; nan(size(allData1)-1)];
                    else
                        % Read data from the specified range and sheet
                        [allData4, timePoints4] = xlsread(fullfile(app.folder, app.filename), sheetname, range4);
                    end
                    % Read Time data from Excel file
                    if isempty(range5)
                        waitfor(msgbox( 'The column you have inserted for time points does not include any entry. Please try to insert this column again.'));
                        return
                    else
                        % Read data from the specified range and sheet

                        
                        [allData5, timePoints5]  = xlsread(fullfile(app.folder, app.filename), sheetname, range5);
                    end
                    allData=[allData1, allData2, allData3, allData4];

                    % Check if the loaded data contains time points
                    if any([isempty(timePoints1), isempty(timePoints2), isempty(timePoints3), isempty(timePoints4), isempty(timePoints5)])
                        waitfor(msgbox('The uploaded file does not include any title or time of injections. Please try again!'));
                        return;
                    end
                    fieldNames = [timePoints1, timePoints2, timePoints3, timePoints4, timePoints5(1)];
                    timePoints5(1) = [];
                    timePoints = timePoints5;
            end

            % Check if any data was read
            if ~isempty(allData)
                app.DataLoaded = true;
                app.EditTableButton.Enable = 'on';
            end

            % Update UI components
            [~,~,ext] = fileparts(fullfile(app.folder, app.filename));
            files = dir(fullfile(app.folder, ['*' ext]));
            app.LoadExcelDataDropDown.Items = {files.name};
            app.LoadExcelDataDropDown.Value = app.filename;


            % % Ensure the time data matches the current time unit
            % currentUnit = app.CurrentTimeUnit;
            % if isfield(app.data, 'Time')
            %     if strcmp(currentUnit, 'Minute')
            %         % Convert times from seconds to minutes
            %         app.data.Time = app.data.Time / 60;
            %         allData(:,1) = allData(:,1) / 60;

            %         % Convert injection times
            %         injection_times = {'t_0', 'oligo_t', 'fccp_1_t', 'fccp_2_t', 'fccp_3_t', 'fccp_4_t', 'inhibit_t'};
            %         for i = 1:numel(injection_times)
            %             if isfield(app.data, injection_times{i}) % Ensure the field exists
            %                 app.data.(injection_times{i}) = app.data.(injection_times{i}) / 60;
            %             end
            %         end
            %     elseif strcmp(currentUnit, 'Second')
            %         % Convert times from minutes to seconds
            %         app.data.Time = app.data.Time * 60;
            %         allData(:,1) = allData(:,1) * 60;
            %
            %         % Convert injection times
            %         injection_times = {'t_0', 'oligo_t', 'fccp_1_t', 'fccp_2_t', 'fccp_3_t', 'fccp_4_t', 'inhibit_t'};
            %         for i = 1:numel(injection_times)
            %             if isfield(app.data, injection_times{i}) % Ensure the field exists
            %                 app.data.(injection_times{i}) = app.data.(injection_times{i}) * 60;
            %             end
            %         end
            %     end
            % end


            injection_fields = {'t_0_i', 'oligo_i', 'f_1_i', 'f_2_i', 'f_3_i', ...
                'f_4_i', 'inhibit_i'};
            injection_times = {'t_0', 'oligo_t', 'fccp_1_t', 'fccp_2_t', 'fccp_3_t', ...
                'fccp_4_t', 'inhibit_t'};
            search_phrases = {'Start Time','Oligomycin','F1','F2','F3', ...
                'F4','Inhibit'};
            injection_label = {'ADP', 'Oligomycin', 'FCCP_1', 'FCCP_2', 'FCCP_3', 'FCCP_4', ...
                'Rot/AA'};

            %determine initial time point
            app.data.(injection_fields{1}) = find(~cellfun('isempty', ...
                strfind(timePoints,search_phrases{1})));


            %insert all data into the data structure
            for i=1:numel(fieldNames)-1
                app.data.(fieldNames{i}) = allData(app.data.t_0_i:end,i);
            end

            app.data.(injection_times{1}) = app.data.Time(app.data.(injection_fields{1}) - app.data.t_0_i +1);


            app.data.Injection_Times = [];
            app.data.Injection_Types = strings(0, 1); % Initialize as an empty string array

            %determine all of the time points for injection
            for timepoint=2:numel(search_phrases)
                % % storing the time and type of injections
                field_name = injection_fields{timepoint};
                label_name = injection_label{timepoint};
                app.data.(field_name) = find(~cellfun('isempty', ...
                    strfind(timePoints, search_phrases{timepoint}))) - app.data.t_0_i +1;
                app.data.(injection_times{timepoint}) =  ...
                    app.data.Time(app.data.(field_name));

                % Check if the field exists and is not empty
                if isfield(app.data, field_name) && ~isempty(app.data.(field_name))
                    % Store the time and type of injections
                    app.data.Injection_Times(end + 1) = app.data.Time(app.data.(field_name));
                    app.data.Injection_Types(end + 1) = label_name;
                end
            end


            %store an array of times for each experimental condition
            timepoints_fields = {'baseline_times', 'oligo_times', 'fccp_1_times', 'fccp_2_times','fccp_3_times','fccp_4_times', 'inhibit_times'};
            times = [1 , app.data.oligo_i, app.data.f_1_i, app.data.f_2_i, app.data.f_3_i, app.data.f_4_i, app.data.inhibit_i];
            for i=1:numel(timepoints_fields)-1
                if ~isempty(app.data.(injection_times{i}))
                    t_i = times(i);

                    t_end = times(i+1)-1;
                    app.data.(timepoints_fields{i}) = app.data.Time(t_i:t_end);
                else
                    app.data.(timepoints_fields{i}) = [];
                end
            end
            app.data.(timepoints_fields{end}) = app.data.Time(app.data.inhibit_i:end);
            app.data.fccp_times = app.data.Time(app.data.f_1_i : app.data.inhibit_i -1);

            % List of items to search for fccc injections
            fccp_search_phrases = {'F1', 'F2', 'F3', 'F4'};

            % Determine number of FCCP injections
            app.params.alphas.num_alphas = sum(ismember(timePoints, fccp_search_phrases));
            nFccp = app.params.alphas.num_alphas;


            alpha_init_vals = app.params.alphas.init_vals;
            % Loop through the number of FCCP injections and assign to app.params
            for i = 1:nFccp
                alpha_name = sprintf('amp_%d', i);
                initial_value = alpha_init_vals(i);
                app.params.alphas.(alpha_name) = initial_value;
            end


            %
            % for field_i = 2:4
            %     % Check if current field exists in the header
            %     if any(strcmp(fieldNames, fieldNames{field_i}))
            %         % Get the index of the current field
            %         currentIndex = find(strcmp(fieldNames, fieldNames{field_i}));
            %
            %         % Check if the current field has any data stored in it
            %         if any(~isempty(allData(:, field_i)))
            %             fprintf('The %s column has data stored in it.\n', fieldNames{field_i});
            %         else
            %             fprintf('The %s column is empty.\n', fieldNames{field_i});
            %         end
            %     else
            %         fprintf('The %s column does not exist in the header.\n', fieldNames{field_i});
            %     end
            % end


            %store an array of times for each experimental condition
            [app.parameters.timePoints, app.parameters.realo2Data, ...
                app.parameters.realOCR, app.parameters.errors] = deal(allData(:,1), allData(:,2), allData(2:end,3), allData(:,4));

            % Check if the OCR is calculated:
            if any(isnan(allData(:, 3)))
                app.parameters.realOCR = -gradient(app.parameters.realo2Data, app.parameters.timePoints);
                app.parameters.realOCR(1) = [];
            end


            app.parameters.RealO2Data = app.parameters.realo2Data(app.data.t_0_i: end);
            app.data.errors = app.parameters.errors(app.data.t_0_i: end);
            app.parameters.RealOCR = app.parameters.realOCR(app.data.t_0_i: end) * 1e6; %correct units to pmol/ml
            app.parameters.oxygen = app.parameters.realo2Data(app.data.t_0_i); %bounds: [1E-6 1]
            app.parameters.expParams.oxygen = app.parameters.oxygen; %bounds: [1E-6 1]
            if app.OxygenEditFieldStatus == false
                app.params.oxygen = app.parameters.oxygen;
                app.OxygenEditField.Value = app.params.oxygen;
            end
            app.params.RealO2Data = app.parameters.RealO2Data;


            % Create a table with Injection_Times and Injection_Types
            injectionTable = table(['Start Time'; app.data.Injection_Types'], [app.parameters.timePoints(app.data.t_0_i); app.data.Injection_Times'], ...
                'VariableNames', {'Injection Types', 'Injection Times'});

            % Update the UITable with the created table
            app.UITable.Data = injectionTable;

            app.FullModelGlobalButton.Enable = 'on';
            app.VisualizationTypeDropDown.Enable = 'on';
        end

        % Value changed function: TotalCytCEditField
        function TotalCytCEditFieldValueChanged(app, event)
            app.params.cytctot = app.TotalCytCEditField.Value;
            app.cytctotEditFieldStatus = true;
            app.params.cytcred = app.params.cytctot - app.CytCOxidizedEditField.Value;
            app.params.cytcredProp = app.CytCOxidizedEditField.Value / app.params.cytctot;
        end

        % Callback function
        function ShowRawDataButtonPushed(app, event)
            app.parameters
        end

        % Value changed function: OptimizationAlgTypeDropDown
        function OptimizationDropDown(app, event)

            value = app.OptimizationAlgTypeDropDown.Value;
            switch value
                case 'Genetic Algorithm'
                    % Clear the axes
                    clearAxes(app.O2_plot);
                    clearAxes(app.OCR_plot);
                    clearAxes(app.Cytc_plot);
                    clearAxes(app.H_N_plot);
                    clearAxes(app.H_P_plot);
                    resetOptimization(app)

                    % Ask the user for custom GA options
                    prompt = {'Enter the number of iterations:',...
                        'Enter the maximum number of generations:', ...
                        'Enter the population size:', ...
                        'Enter the crossover fraction (0-1):', ...
                        'Enter the mutation rate (0-1):'};
                    dlgtitle = 'Custom GA Options';
                    dims = [1 35];
                    definput = {'10', '2000', '200', '0.8', '0.01'}; % Default values for custom options
                    answer = inputdlg(prompt, dlgtitle, dims, definput);

                    if isempty(answer)
                        % User pressed cancel
                        return;
                    else
                        % Convert the input to numbers
                        No_Iter = str2double(answer{1});
                        max_generations = str2double(answer{2});
                        population_size = str2double(answer{3});
                        crossover_fraction = str2double(answer{4});
                        mutation_rate = str2double(answer{5});

                        if any(isnan([No_Iter, max_generations, population_size, crossover_fraction, mutation_rate])) || ...
                                any([No_Iter, max_generations, population_size, crossover_fraction, mutation_rate] <= 0) || ...
                                crossover_fraction > 1 || mutation_rate > 1
                            % Invalid input, show an error message
                            errordlg('Please enter valid positive numbers for custom options. Crossover and mutation rates must be between 0 and 1.', 'Invalid Input');
                            return;
                        end
                    end


                    % Create a progress dialog
                    progressDlg = uiprogressdlg(app.UIFigure, 'Title', 'Optimizing', ...
                        'Message', 'Running Genetic Algorithm...', 'Indeterminate', 'off');
                    Current_iter_no = 1;

                    % Set custom GA options
                    options = optimoptions('ga', ...
                        'PopulationSize', population_size, ...
                        'MaxGenerations', max_generations, ...
                        'CrossoverFraction', crossover_fraction, ...
                        'MutationFcn', {@mutationadaptfeasible, mutation_rate}, ...
                        'Display', 'iter', ...
                        'UseParallel', true, ...
                        'PlotFcn', {@gaplotbestf, @gaplotstopping, @gaplotgenealogy}, ...
                        'OutputFcn', @(options, state, flag) GAOutputFunction(options, state, flag, progressDlg, Current_iter_no, No_Iter, []));


                    % Run GA optimization with custom options
                    try

                        [optimizedParams, fval, app.gaInfo] = optimize_model_with_ga_custom(app.params, app.data, app.Model, app.modeleqns, No_Iter, options, progressDlg);
                        msgbox('Optimization by Genetic Algorithm with Custom Options Finished!')
                        app.ResultsTypeDropDown.Enable = 'on';

                        % Update parameters with optimized values
                        app.params.f0_Vmax = optimizedParams(1);
                        app.params.cytctot = optimizedParams(2);
                        app.params.f0_Km = optimizedParams(3);
                        app.params.fIV_Vmax = optimizedParams(4);
                        app.params.fIV_Km = optimizedParams(5);
                        app.params.fIV_K = optimizedParams(6);
                        app.params.fV_Vmax = optimizedParams(7);
                        app.params.fV_K = optimizedParams(8);
                        app.params.fV_Km = optimizedParams(9);
                        app.params.p_alpha = optimizedParams(10);
                        app.params.cytcredProp = optimizedParams(11);

                        app.params.cytcox = optimizedParams(2) * optimizedParams(11);
                        app.params.cytcred = app.params.cytctot - app.params.cytcox;


                        % Dynamically update FCCP-related parameters
                        if strcmp(app.Model.Type, 'FullModelGlobal')
                            % Get the number of FCCP injections from app.params.alphas.num_alphas
                            num_alphas = app.params.alphas.num_alphas;

                            % Update the FCCP injection parameters in app.params.alphas
                            for i = 1:num_alphas
                                amp_field = sprintf('amp_%d', i);
                                app.params.alphas.(amp_field) = optimizedParams(11 + i); % Adjusting indices for FCCP params
                            end

                            % Set the attenuateProp parameter, following the FCCP params
                            app.params.attenuateProp = optimizedParams(12 + num_alphas);
                        end

                        % Solve the model with optimized parameters and plot results
                        [t, y] = solver_mito(app.params, app.data, app.Model, app.modeleqns);

                        % Store the values calculated for each variable
                        [cytcred, o2, Hn, Hp] = deal(y(:,1), y(:,2), y(:,3), y(:,4));

                        % Calculate the OCR values from the oxygen
                        calcOCR = .5 * ((app.params.fIV_Vmax .* o2) ./ (app.params.fIV_Km .* (1 + (app.params.fIV_K ./ cytcred)) + o2)) .* Hn ./ Hp;
                        % calcOCR_test = -gradient(o2)* 1e6;
                        calcOCR = calcOCR * 1e6;

                        app.results.OCR = calcOCR;
                        app.results.CytCRed = cytcred;
                        app.results.O2 = o2;
                        app.results.Hn = Hn;
                        app.results.Hp = Hp;
                        app.results.ModelType = app.Model.Type;

                        app.OptimOutput.Params = optimizedParams;
                        app.OptimOutput.Fval = fval;

                        app.ModelOptimized = true;
                        app.ResultsTypeDropDown.Enable = 'on';

                        % Inform user about the success of optimization
                        infoMessage = sprintf('Genetic Algorithm with Custom Options optimization finished successfully.\nOptimized parameters have been applied.\nObjective function value: %f', fval);
                        msgbox(infoMessage, 'Optimization Success');

                    catch ME
                        % Display an error message if something goes wrong
                        errordlg(['An error occurred: ', ME.message], 'Error');
                    end

                    % Close the progress dialog
                    if isvalid(progressDlg)
                        progressDlg.Value = 1.0;
                        progressDlg.Message = 'All GA runs complete!';
                        close(progressDlg);
                    end


                case 'Enhanced GA (Bootstrap)'
                    % Clear the axes
                    clearAxes(app.O2_plot);
                    clearAxes(app.OCR_plot);
                    clearAxes(app.Cytc_plot);
                    clearAxes(app.H_N_plot);
                    clearAxes(app.H_P_plot);
                    resetOptimization(app)

                    if feature('numCores') > 4 % Use parallel only if there are more than 4 cores
                        useParallel = true;
                    else
                        useParallel = false;
                    end

                    % Ask the user to insert max_iter, bootstrap_samples, and generations
                    prompt = {'Enter the number of bootstrap samples:', ...
                        'Enter the maximum number of generations:', ...
                        'Enter the population size:', ...
                        'Enter the crossover fraction (0-1):', ...
                        'Enter the mutation rate (0-1):'};
                    dlgtitle = 'Input';
                    dims = [1 35];
                    definput = {'50', '1000', '100', '0.7', '0.02'}; % Default values
                    answer = inputdlg(prompt, dlgtitle, dims, definput);

                    if isempty(answer)
                        % User pressed cancel
                        return;
                    else
                        % Convert the input to numbers
                        bootstrap_samples = str2double(answer{1});
                        max_generations = str2double(answer{2});
                        population_size = str2double(answer{3});
                        crossover_fraction = str2double(answer{4});
                        mutation_rate = str2double(answer{5});


                        if any(isnan([bootstrap_samples, max_generations, population_size, crossover_fraction, mutation_rate])) || ...
                                any([bootstrap_samples, max_generations, population_size, crossover_fraction, mutation_rate] <= 0) || ...
                                crossover_fraction > 1 || mutation_rate > 1
                            % Invalid input, show an error message
                            errordlg('Please enter valid positive numbers for max_iter, bootstrap_samples, and generations.', 'Invalid Input');
                            return;
                        end
                    end

                    % Run Enhanced GA (Bootstrap) optimization
                    try
                        % Create a progress dialog
                        progressDlg = uiprogressdlg(app.UIFigure, 'Title', 'Optimizing', ...
                            'Message', 'Enhanced GA (Bootstrap)...', 'Indeterminate', 'off');

                        % Initialize a handle object to store the counter
                        progressHandle = struct('counter', 0);

                        % Set custom GA options
                        options = optimoptions('ga', ...
                            'PopulationSize', population_size, ...
                            'MaxGenerations', max_generations, ...
                            'CrossoverFraction', crossover_fraction, ...
                            'MutationFcn', {@mutationadaptfeasible, mutation_rate}, ...
                            'Display', 'iter', ...
                            'UseParallel', useParallel, ...
                            'PlotFcn', {@gaplotbestf, @gaplotstopping, @gaplotgenealogy}, ...
                            'OutputFcn', @(options, state, flag) GAOutputFunction(options, state, flag, progressDlg, [], [],  progressHandle, bootstrap_samples));

                        [optimizedParams, fval, app.gaInfo, confIntervals] = optimize_model_with_enhanced_ga(app.params, app.data, app.Model, app.modeleqns, bootstrap_samples, progressHandle, options, progressDlg);
                        msgbox('Optimization by Enhanced GA (Bootstrap) Finished!')
                        app.ResultsTypeDropDown.Enable = 'on';

                        % Update parameters with optimized values
                        app.params.f0_Vmax = optimizedParams(1);
                        app.params.cytctot = optimizedParams(2);
                        app.params.f0_Km = optimizedParams(3);
                        app.params.fIV_Vmax = optimizedParams(4);
                        app.params.fIV_Km = optimizedParams(5);
                        app.params.fIV_K = optimizedParams(6);
                        app.params.fV_Vmax = optimizedParams(7);
                        app.params.fV_K = optimizedParams(8);
                        app.params.fV_Km = optimizedParams(9);
                        app.params.p_alpha = optimizedParams(10);
                        app.params.cytcredProp = optimizedParams(11);

                        app.params.cytcox = optimizedParams(2) * optimizedParams(11);
                        app.params.cytcred = app.params.cytctot - app.params.cytcox;


                        % Dynamically update FCCP-related parameters
                        if strcmp(app.Model.Type, 'FullModelGlobal')
                            % Get the number of FCCP injections from app.params.alphas.num_alphas
                            num_alphas = app.params.alphas.num_alphas;

                            % Update the FCCP injection parameters in app.params.alphas
                            for i = 1:num_alphas
                                amp_field = sprintf('amp_%d', i);
                                app.params.alphas.(amp_field) = optimizedParams(11 + i); % Adjusting indices for FCCP params
                            end

                            % Set the attenuateProp parameter, following the FCCP params
                            app.params.attenuateProp = optimizedParams(12 + num_alphas);
                        end

                        % Solve the model with optimized parameters and plot results
                        [t, y] = solver_mito(app.params, app.data, app.Model, app.modeleqns);

                        % Store the values calculated for each variable
                        [cytcred, o2, Hn, Hp] = deal(y(:, 1), y(:, 2), y(:, 3), y(:, 4));

                        % Calculate the OCR values from the oxygen
                        calcOCR = .5 * ((app.params.fIV_Vmax .* o2) ./ (app.params.fIV_Km .* (1 + (app.params.fIV_K ./ cytcred)) + o2)) .* Hn ./ Hp;
                        calcOCR = calcOCR * 1e6;

                        app.results.OCR = calcOCR;
                        app.results.CytCRed = cytcred;
                        app.results.O2 = o2;
                        app.results.Hn = Hn;
                        app.results.Hp = Hp;
                        app.results.ModelType = app.Model.Type;

                        app.OptimOutput.Params = optimizedParams;
                        app.OptimOutput.Fval = fval;
                        app.OptimOutput.confIntervals = confIntervals;

                        app.ModelOptimized = true;
                        app.ResultsTypeDropDown.Enable = 'on';

                        % Inform user about the success of optimization
                        infoMessage = sprintf('Enhanced GA (Bootstrap) optimization finished successfully.\nOptimized parameters have been applied.\nObjective function value: %f', fval);
                        msgbox(infoMessage, 'Optimization Success');

                    catch ME
                        % Display an error message if something goes wrong
                        errordlg(['An error occurred: ', ME.message], 'Error');
                    end

                   % Close the progress dialog
                    if isvalid(progressDlg)
                        progressDlg.Value = 1.0;
                        progressDlg.Message = 'All GA runs complete!';
                        close(progressDlg);
                    end

                case 'Enhanced GA (Hybrid Function)'
                    % Clear the axes
                    clearAxes(app.O2_plot);
                    clearAxes(app.OCR_plot);
                    clearAxes(app.Cytc_plot);
                    clearAxes(app.H_N_plot);
                    clearAxes(app.H_P_plot);
                    resetOptimization(app)

                    % Ask the user to insert max_iter
                    prompt = {'Enter the maximum number of generations:'};
                    dlgtitle = 'Input';
                    dims = [1 35];
                    definput = {'800'}; % Default value for max_Gen
                    answer = inputdlg(prompt, dlgtitle, dims, definput);

                    if isempty(answer)
                        % User pressed cancel
                        return;
                    else
                        % Convert the input to a number
                        max_gen = str2double(answer{1});

                        if isnan(max_gen) || max_gen <= 0
                            % Invalid input, show an error message
                            errordlg('Please enter a valid positive number for maximum number of generations.', 'Invalid Input');
                            return;
                        end
                    end

                    % Run Hybrid optimization
                    try

                        % Create a progress dialog
                        progressDlg = uiprogressdlg(app.UIFigure, 'Title', 'Optimizing', ...
                            'Message', 'Enhanced GA (Hybrid Function)...', 'Indeterminate', 'off');

                        [optimizedParams, fval, app.gaInfo] = optimize_model_with_hybrid(app.params, app.data, app.Model, app.modeleqns, max_gen, progressDlg);
                        msgbox('Optimization by Hybrid Function Finished!')
                        app.ResultsTypeDropDown.Enable = 'on';

                        % Update parameters with optimized values
                        app.params.f0_Vmax = optimizedParams(1);
                        app.params.cytctot = optimizedParams(2);
                        app.params.f0_Km = optimizedParams(3);
                        app.params.fIV_Vmax = optimizedParams(4);
                        app.params.fIV_Km = optimizedParams(5);
                        app.params.fIV_K = optimizedParams(6);
                        app.params.fV_Vmax = optimizedParams(7);
                        app.params.fV_K = optimizedParams(8);
                        app.params.fV_Km = optimizedParams(9);
                        app.params.p_alpha = optimizedParams(10);
                        app.params.cytcredProp = optimizedParams(11);

                        app.params.cytcox = optimizedParams(2) * optimizedParams(11);
                        app.params.cytcred = app.params.cytctot - app.params.cytcox;


                        % Dynamically update FCCP-related parameters
                        if strcmp(app.Model.Type, 'FullModelGlobal')
                            % Get the number of FCCP injections from app.params.alphas.num_alphas
                            num_alphas = app.params.alphas.num_alphas;

                            % Update the FCCP injection parameters in app.params.alphas
                            for i = 1:num_alphas
                                amp_field = sprintf('amp_%d', i);
                                app.params.alphas.(amp_field) = optimizedParams(11 + i); % Adjusting indices for FCCP params
                            end

                            % Set the attenuateProp parameter, following the FCCP params
                            app.params.attenuateProp = optimizedParams(12 + num_alphas);
                        end

                        % Solve the model with optimized parameters and plot results
                        [t, y] = solver_mito(app.params, app.data, app.Model, app.modeleqns);

                        % Store the values calculated for each variable
                        [cytcred, o2, Hn, Hp] = deal(y(:, 1), y(:, 2), y(:, 3), y(:, 4));

                        % Calculate the OCR values from the oxygen
                        calcOCR = .5 * ((app.params.fIV_Vmax .* o2) ./ (app.params.fIV_Km .* (1 + (app.params.fIV_K ./ cytcred)) + o2)) .* Hn ./ Hp;
                        calcOCR = calcOCR * 1e6;

                        app.results.OCR = calcOCR;
                        app.results.CytCRed = cytcred;
                        app.results.O2 = o2;
                        app.results.Hn = Hn;
                        app.results.Hp = Hp;
                        app.results.ModelType = app.Model.Type;

                        app.OptimOutput.Params = optimizedParams;
                        app.OptimOutput.Fval = fval;

                        app.ModelOptimized = true;
                        app.ResultsTypeDropDown.Enable = 'on';

                        % Inform user about the success of optimization
                        infoMessage = sprintf('Enhanced GA (Hybrid Function) optimization finished successfully.\nOptimized parameters have been applied.\nObjective function value: %f', fval);
                        msgbox(infoMessage, 'Optimization Success');

                        % Close the progress dialog
                    if isvalid(progressDlg)
                        progressDlg.Value = 1.0;
                        progressDlg.Message = 'All GA runs complete!';
                        close(progressDlg);
                    end

                    catch ME
                        % Display an error message if something goes wrong
                        errordlg(['An error occurred: ', ME.message], 'Error');
                    end

                case 'Batch GA Optimization'
                    % Clear the axes
                    clearAxes(app.O2_plot);
                    clearAxes(app.OCR_plot);
                    clearAxes(app.Cytc_plot);
                    clearAxes(app.H_N_plot);
                    clearAxes(app.H_P_plot);
                    resetOptimization(app)

                    % Prompt user for the number of runs and other GA parameters
                    prompt = {'Enter the number of runs:', ...
                        'Enter the maximum number of generations:', ...
                        'Enter the population size:',...
                        'Enter the crossover fraction (0-1):', ...
                        'Enter the mutation rate (0-1):'};
                    dlgtitle = 'GA Multiple Runs Options';
                    dims = [1 35];
                    definput = {'10', '2000', '200', '0.8', '0.01'};
                    answer = inputdlg(prompt, dlgtitle, dims, definput);

                    if isempty(answer)
                        return;  % User pressed cancel
                    end

                    % Convert inputs
                    numRuns = str2double(answer{1});
                    max_generations = str2double(answer{2});
                    population_size = str2double(answer{3});
                    crossover_fraction = str2double(answer{4});
                    mutation_rate = str2double(answer{5});

                    if any(isnan([numRuns, max_generations, population_size, crossover_fraction, mutation_rate])) || ...
                            any([numRuns, max_generations, population_size, crossover_fraction, mutation_rate] <= 0) || ...
                            crossover_fraction > 1 || mutation_rate > 1
                        errordlg('Invalid input. Please enter valid numbers.', 'Input Error');
                        return;
                    end

                    % Initialize arrays to store results for each run
                    optimizedParamsArray = cell(numRuns, 1);
                    finalErrorsArray = zeros(numRuns, 1);
                    gaInfoArray = cell(numRuns, 1);

                    % Create a progress dialog
                    progressDlg = uiprogressdlg(app.UIFigure, 'Title', 'Batch GA Optimization', ...
                        'Message', 'Starting optimization...', 'Indeterminate', 'off', 'Value', 0);

                    % Loop through each run
                    for runIdx = 1:numRuns
                        % Update progress dialog for each run
                        progressDlg.Message = sprintf('Running GA iteration %d of %d...', runIdx, numRuns);
                        progressDlg.Value = (runIdx - 1) / numRuns;

                        % Create GA options for this run
                        options = optimoptions('ga', ...
                            'PopulationSize', population_size, ...
                            'MaxGenerations', max_generations, ...
                            'CrossoverFraction', crossover_fraction, ...
                            'MutationFcn', {@mutationadaptfeasible, mutation_rate}, ...
                            'Display', 'iter', ...
                            'UseParallel', true, ...
                            'OutputFcn', @(options, state, flag) GAOutputFunction(options, state, flag, progressDlg, runIdx, numRuns, []));

                        % Run the GA optimization
                        try
                            [optParams, fval, gaInfo] = optimize_ga_batch(app.params, app.data, app.Model, app.modeleqns, options);
                            optimizedParamsArray{runIdx} = optParams;  % Store optimized parameters
                            finalErrorsArray(runIdx) = fval;  % Store final error value
                            gaInfoArray{runIdx} = gaInfo;
                        catch ME
                            errordlg(['Error during run ' num2str(runIdx) ': ' ME.message], 'Optimization Error');
                        end
                    end

                    % Close the progress dialog
                    if isvalid(progressDlg)
                        progressDlg.Value = 1.0;
                        progressDlg.Message = 'All GA runs complete!';
                        pause(0.5);  % Small pause to let user see final message
                        close(progressDlg);
                    end

                    % Save the results for further analysis
                    resultsFile = fullfile('OptimizedSolutions', 'GA_MultipleRuns_Results.mat');
                    save(resultsFile, 'optimizedParamsArray', 'finalErrorsArray', 'gaInfoArray');

                    msgbox('Multiple GA Runs Completed and Results Saved.', 'Success');
            end


            % case 'Resume GA with Previous Parameters'
        %     % Clear the axes
        %     clearAxes(app.O2_plot);
        %     clearAxes(app.OCR_plot);
        %     clearAxes(app.Cytc_plot);
        %     clearAxes(app.H_N_plot);
        %     clearAxes(app.H_P_plot);
        %
        %     if  app.PrevParamsLoaded  % Ensure previous parameters are loaded
        %
        %         % Define the field names for the first six parameters
        %         fieldNames = {'f0_Vmax', 'cytctot', 'f0_Km', 'fIV_Vmax', 'fIV_Km', ...
        %             'fIV_K', 'fV_Vmax', 'fV_K', 'fV_Km', 'p_alpha', 'cytcredProp', ...
        %             'cytcox', 'cytcred', 'amp_1', 'amp_2', 'amp_3', 'amp_4', 'attenuateProp'};
        %
        %         % Retrieve optimized parameters from previously loaded results
        %         % Loop through each field name and assign the value
        %
        %         for i = 1:numel(app.PrevResults.params)
        %             app.params.(fieldNames{i}) = app.PrevResults.params.(fieldNames{i});
        %         end
        %
        %         % Ask the user to insert max_iter
        %         prompt = {'Enter the maximum number of generations:'};
        %         dlgtitle = 'Input';
        %         dims = [1 35];
        %         definput = {'800'}; % Default value for max_Gen
        %         answer = inputdlg(prompt, dlgtitle, dims, definput);
        %
        %         if isempty(answer)
        %             % User pressed cancel
        %             return;
        %         else
        %             % Convert the input to a number
        %             max_gen = str2double(answer{1});
        %
        %             if isnan(max_gen) || max_gen <= 0
        %                 % Invalid input, show an error message
        %                 errordlg('Please enter a valid positive number for maximum number of generations.', 'Invalid Input');
        %                 return;
        %             end
        %         end
        %
        %
        %         % Run Hybrid optimization
        %         try
        %
        %
        %             % Create a progress dialog
        %             progressDlg = uiprogressdlg(app.UIFigure, 'Title', 'Optimizing', ...
        %                 'Message', 'Enhanced GA (Hybrid Function)...', 'Indeterminate', 'off');
        %
        %             % [optimizedParams, fval, app.gaInfo] = optimize_model_with_ga_custom(app.params, app.data, app.Model, app.modeleqns, No_Iter, options, progressDlg);
        %             [optimizedParams, fval, app.gaInfo] = optimize_model_with_hybrid(app.params, app.data, app.Model, app.modeleqns, max_gen, progressDlg);
        %             msgbox('Optimization by Genetic Algorithm with Custom Options Finished!')
        %             app.ResultsTypeDropDown.Enable = 'on';
        %
        %             % Update parameters with optimized values
        %             app.params.f0_Vmax = optimizedParams(1);
        %             app.params.cytctot = optimizedParams(2);
        %             app.params.f0_Km = optimizedParams(3);
        %             app.params.fIV_Vmax = optimizedParams(4);
        %             app.params.fIV_Km = optimizedParams(5);
        %             app.params.fIV_K = optimizedParams(6);
        %             app.params.fV_Vmax = optimizedParams(7);
        %             app.params.fV_K = optimizedParams(8);
        %             app.params.fV_Km = optimizedParams(9);
        %             app.params.p_alpha = optimizedParams(10);
        %             app.params.cytcredProp = optimizedParams(11);
        %
        %             app.params.cytcox = optimizedParams(2) * optimizedParams(11);
        %             app.params.cytcred = app.params.cytctot - app.params.cytcox;
        %
        %
        %             % Dynamically update FCCP-related parameters
        %             if strcmp(app.Model.Type, 'FullModelGlobal')
        %                 % Get the number of FCCP injections from app.params.alphas.num_alphas
        %                 num_alphas = app.params.alphas.num_alphas;
        %
        %                 % Update the FCCP injection parameters in app.params.alphas
        %                 for i = 1:num_alphas
        %                     amp_field = sprintf('amp_%d', i);
        %                     app.params.alphas.(amp_field) = optimizedParams(11 + i); % Adjusting indices for FCCP params
        %                 end
        %
        %                 % Set the attenuateProp parameter, following the FCCP params
        %                 app.params.attenuateProp = optimizedParams(12 + num_alphas);
        %             end
        %
        %             % Solve the model with optimized parameters and plot results
        %             [t, y] = solver_mito(app.params, app.data, app.Model, app.modeleqns);
        %
        %             % Store the values calculated for each variable
        %             [cytcred, o2, Hn, Hp] = deal(y(:,1), y(:,2), y(:,3), y(:,4));
        %
        %             % Calculate the OCR values from the oxygen
        %             calcOCR = .5 * ((app.params.fIV_Vmax .* o2) ./ (app.params.fIV_Km .* (1 + (app.params.fIV_K ./ cytcred)) + o2)) .* Hn ./ Hp;
        %             calcOCR = calcOCR * 1e6;
        %
        %             app.results.OCR = calcOCR;
        %             app.results.CytCRed = cytcred;
        %             app.results.O2 = o2;
        %             app.results.Hn = Hn;
        %             app.results.Hp = Hp;
        %             app.results.ModelType = app.Model.Type;
        %
        %             app.OptimOutput.Params = optimizedParams;
        %             app.OptimOutput.Fval = fval;
        %
        %             app.ModelOptimized = true;
        %             app.ResultsTypeDropDown.Enable = 'on';
        %
        %             % Inform user about the success of optimization
        %             infoMessage = sprintf('Genetic Algorithm with Custom Options optimization finished successfully.\nOptimized parameters have been applied.\nObjective function value: %f', fval);
        %             msgbox(infoMessage, 'Optimization Success');
        %
        %         catch ME
        %             % Display an error message if something goes wrong
        %             errordlg(['An error occurred: ', ME.message], 'Error');
        %         end
        %
        %         % Close the progress dialog
        %         if isvalid(progressDlg)
        %             close(progressDlg);
        %         end
        %     else
        %         msgbox('No previously optimized parameters loaded. Please load previous parameters from the file menue before resuming.', 'Error', 'error');
        %     end


        end

        % Button pushed function: EditTableButton
        function EditTableButtonPushed(app, event)
            % Create figure
            fig = figure('Name', 'Excel GUI', 'Position', [100, 100, 600, 400]);

            % Create menu
            fileMenu = uimenu(fig, 'Label', 'ExcelFile');
            uimenu(fileMenu, 'Label', 'Open Excel File', 'Callback', @openExcel);
            uimenu(fileMenu, 'Label', 'Save Excel File', 'Callback', @saveExcel);
            uimenu(fileMenu, 'Label', 'Save As', 'Callback', @saveAsExcel);

            % Create uicontrols
            uploadButton = uicontrol(fig, 'Style', 'pushbutton', 'String', 'Upload Excel File', ...
                'Position', [20, 350, 150, 30], 'Callback', @openExcel);

            addRecordButton = uicontrol(fig, 'Style', 'pushbutton', 'String', 'Add Record', ...
                'Position', [200, 350, 150, 30], 'Callback', @addRecord);

            % Create table
            tableData = {};
            columnNames = {};
            uitable('Parent', fig, 'Data', tableData, 'ColumnName', columnNames, ...
                'Position', [20, 20, 560, 300], 'Tag', 'excelTable');

            function openExcel(~, ~)
                [filename, pathname] = uigetfile('*.xlsx', 'Select Excel File');
                if filename ~= 0
                    % Read data from Excel file
                    [~, ~, raw] = xlsread(fullfile(pathname, filename));

                    % Set column names
                    columnNames = raw(1,:);

                    % Set table data
                    tableData = raw(2:end,:);

                    % Update table
                    set(findobj(fig, 'Tag', 'excelTable'), 'Data', tableData, 'ColumnName', columnNames);
                end
            end

            function addRecord(~, ~)
                % Prompt user for new record values
                prompt = {'Enter value for Column1:', 'Enter value for Column2:', 'Enter value for Column3:', 'Enter value for Column4:'};
                dlgtitle = 'Add Record';
                dims = [1 35];
                definput = {'', '', '', ''};
                newRecord = inputdlg(prompt,dlgtitle,dims,definput);

                % Add the new record to the table
                if ~isempty(newRecord)
                    tableData = get(findobj(fig, 'Tag', 'excelTable'), 'Data');
                    % Convert newRecord to a row cell array to match the dimensions
                    newRecord = newRecord(:)';
                    tableData = [tableData; newRecord]; % Remove the transpose here
                    set(findobj(fig, 'Tag', 'excelTable'), 'Data', tableData);
                end
            end

            function saveExcel(~, ~)
                % Get table data
                tableData = get(findobj(fig, 'Tag', 'excelTable'), 'Data');
                % Get column names
                columnNames = get(findobj(fig, 'Tag', 'excelTable'), 'ColumnName');

                % Prompt user for filename
                [filename, pathname] = uiputfile('*.xlsx', 'Save Excel File');
                if filename ~= 0
                    % Write data to Excel file
                    xlswrite(fullfile(pathname, filename), [columnNames; tableData]);
                end
            end

            function saveAsExcel(~, ~)
                % Prompt user to specify the filename and path for saving the Excel file
                [filename, pathname] = uiputfile('*.xlsx', 'Save As');

                % Check if the user canceled the operation
                if isequal(filename,0) || isequal(pathname,0)
                    disp('User canceled the operation');
                    return;
                end

                % Get the table data from the GUI
                tableData = get(findobj(gcf, 'Tag', 'excelTable'), 'Data');
                columnNames = get(findobj(gcf, 'Tag', 'excelTable'), 'ColumnName');

                % Check if tableData is empty
                if isempty(tableData)
                    disp('No data to save');
                    return;
                end

                % Check if the column names match the data dimensions
                if numel(columnNames) ~= size(tableData, 2)
                    disp('Number of column names does not match the number of columns in the data');
                    return;
                end

                % Write data to the Excel file
                try
                    xlswrite(fullfile(pathname, filename), [columnNames; tableData]);
                    disp('Data saved successfully');
                catch
                    disp('Error saving data to Excel file');
                end
            end

            % function ExcelDataLoadButtonPushed(app, event)
            % [app.filename, app.folder] = uigetfile('*.xls*');
            %  input_table = readtable(fullfile(app.folder,app.filename));
            %  [~,~,ext] = fileparts(fullfile(app.folder,app.filename));
            %  files = dir(fullfile(app.folder,['*' ext]));
            %  app.LoadExcelDataDropDown.Items = {files.name};
            %  app.LoadExcelDataDropDown.Value = app.filename;

            %  output_table = TableConverter(input_table);
            %
            %  app.InputTable.Data = input_table;
            %  app.InputTable.ColumnName=input_table.Properties.VariableNames;
            %  app.OutputTable.Data = output_table;
            %  app.OutputTable.ColumnName=output_table.Properties.VariableNames;
            %  app.InputTableLabel.Visible ='on';
            %  app.OutputTableLabel.Visible ='on';
            %  app.InputTable.Visible ='on';
            %  app.OutputTable.Visible ='on';
            % end
        end

        % Value changed function: CytCOxidizedEditField
        function CytCOxidizedEditFieldValueChanged(app, event)
            app.params.cytcox = app.CytCOxidizedEditField.Value;
            app.cytcoxEditFieldStatus = true;
            app.params.cytcred = app.TotalCytCEditField.Value- app.params.cytcox;
            app.params.cytcredProp = app.params.cytcox / app.TotalCytCEditField.Value;
        end

        % Value changed function: IMSProtonHsubPsubEditField
        function IMSProtonHsubPsubEditFieldValueChanged(app, event)
            value = app.IMSProtonHsubPsubEditField.Value;
            Cal_pH_Value = -log10(value *1E-6);
            % Check for valid pH value
            validity = true;
            if (Cal_pH_Value < 2) || (Cal_pH_Value > 12)
                waitfor(msgbox('Not a valid pH.','Invalid pH'));
                validity = false;
            elseif (Cal_pH_Value >= 11.88)
                waitfor(msgbox('Too basic for this system. Must be pH < 11.88.','pH too high'));
                validity = false;
            end
            app.params.Hp = app.IMSProtonHsubPsubEditField.Value;
            app.IMSProtonHsubPsubEditFieldStatus = true;
            % app.params.Hn = ;


            % oldHp = 0;
            % newHp = 0;
            % getVal = str2double(get(value,'String'));

            % if isnan(getVal) %if not, throw error box and reset value
            %         msgbox('Please input a valid number.','Not a number');
            %
            %         %get the concentration value for resetting the edit box
            %         getHpconc = getfield(app.parameters,'Hp');
            %
            %         oldHp = -log10(getHpconc *1E-6);
            %
            %         set(value,'String',oldHp);
            % else %if so, then update the model with new value
            %         %Hp from the given pH
            %         if checkpH(getVal)
            %                 newHp = 10^-getVal * 1E6;
            %                 app.parameters = setfield(app.parameters,'Hp',newHp);
            %         else
            %                 %get the concentration value for resetting the edit box
            %                 getHpconc = getfield(app.parameters,'Hp');
            %
            %                 oldHp = -log10(getHpconc *1E-6);
            %                 set(value,'String',oldHp);
            %         end
            % end


        end

        % Value changed function: OxygenEditField
        function OxygenEditFieldValueChanged(app, event)
            app.params.oxygen = app.OxygenEditField.Value;
            app.OxygenEditFieldStatus = true;
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;

        end

        % Selection changed function: ButtonGroupModel
        function ButtonGroupModelSelectionChanged(app, event)
            selectedButton = event.NewValue.Text;
            if strcmp(selectedButton, 'Baseline Model')
                app.Model.Type   = 'BaselineModel';
                % update model parameters
                app.params.f0_Vmax = 153.9776; %bounds: [0.1 1000]
                app.params.f0_Km = 0.1012; %bounds: [0.1 1E4]
                app.params.fIV_Vmax = 3.4011; %bounds: [0.01 10]
                app.params.fIV_Km = 0.000101; %bounds: [0.1 1E4]
                app.params.fIV_K = 0.0061; %bounds: [0.1 1E4]
                app.params.fV_Vmax = 163.4067; %bounds: [1 1E4]
                app.params.fV_Km = 0.0012; %bounds: [1E-6 1]
                app.params.fV_K = 0.0357; %bounds: [1 1E4]
                app.params.p_alpha = 0.0068; %bounds: [1E-9 1]


                % Set bounds for the parameters
                app.params.rangeLow = [0.1, 1e2, 0.1, 0.001, 1e-7, 1e-6, 1, 1e-6, 1e-5, 1e-6, 0];
                app.params.rangeUp =  [1000, 7e2, 1000, 10, 1e-3, 0.01, 1e4, 0.01, 1e-1, 0.01, 1];

            else
                app.Model.Type = 'FullModelGlobal';
                % update model parameters
                app.params.f0_Vmax = 41.479; %bounds: [0.01 10]
                app.params.f0_Km = 0.1012; %bounds: [0.1 1E4]
                app.params.fIV_Vmax = 0.75; %bounds: [0.01 10]
                app.params.fIV_Km = 1.46*1e-4; %bounds: [0.1 1E4]
                app.params.fIV_K = 0.023; %bounds: [0.1 1E4]
                app.params.fV_Vmax = 99.22; %bounds: [1 1E4]
                app.params.fV_Km = 3.72*1e-3; %bounds: [1E-6 1]
                app.params.fV_K = 5.6*1e-3; %bounds: [1 1E4]
                app.params.p_alpha = 0.00778; %bounds: [1E-9 1]

                app.params.attenuateProp = 2.12E-6;

                % Define number of FCCP injections dynamically
                num_alphas = app.params.alphas.num_alphas;

                % Update the FCCP injection parameters in app.params.alphas
                for i = 1:num_alphas
                    amp_field = sprintf('amp_%d', i);
                    app.params.alphas.(amp_field) = app.params.alphas.init_vals(i); 
                end

                range_low_alphas = app.params.alphas.range_low(1: num_alphas);
                range_up_alphas = app.params.alphas.range_up(1: num_alphas);

                app.params.range_low_alphas = range_low_alphas;
                app.params.range_up_alphas = range_up_alphas;

                % Set bounds for the parameters
                app.params.rangeLow = [0.1, 1e2, 0.1, 0.001, 1e-7, 1e-6, 1, 1e-6, 1e-5, 1e-6, 0, range_low_alphas, 1e-10];
                app.params.rangeUp =  [1000, 7e2, 1000, 10, 1e-3, 0.01, 1e4, 0.01, 1e-1, 0.01, 1, range_up_alphas, 1e-3];
            end

            % update initial values and relate parameters
            app.params.cytctot = app.TotalCytCEditField.Value;
            app.params.cytcox = app.CytCOxidizedEditField.Value;
            app.params.cytcred = app.params.cytctot - app.params.cytcox ;
            app.params.cytcredProp = app.params.cytcox  / app.params.cytctot;

            app.params.Hn = app.MatrixProtonHsubNsubEditField.Value;
            app.params.Hp = app.IMSProtonHsubPsubEditField.Value;
            app.params.pH = -log10(app.params.Hn *1E-6);


        end

        % Value changed function: MatrixProtonHsubNsubEditField
        function MatrixProtonHsubNsubEditFieldValueChanged(app, event)
            value = app.MatrixProtonHsubNsubEditField.Value;
            % params.ctrlParams.rho = -log10( value*1E-6);
            % params.expParams.rho = params.ctrlParams.rho;
            % params.Hp = params.ctrlParams.rho;
            Cal_pH_Value = -log10(value *1E-6);
            % Check for valid pH value
            validity = true;
            if (Cal_pH_Value < 2) || (Cal_pH_Value > 12)
                waitfor(msgbox('Not a valid pH.','Invalid pH'));
                validity = false;
            elseif (Cal_pH_Value >= 11.88)
                waitfor(msgbox('Too basic for this system. Must be pH < 11.88.','pH too high'));
                validity = false;
            end
            app.params.Hn = app.MatrixProtonHsubNsubEditField.Value;
            app.params.pH = -log10(app.params.Hn *1E-6);
            app.MatrixProtonHsubNsubEditFieldStatus = true;
        end

        % Callback function
        function LoadExcelDataDropDownValueChanged(app, event)
            value = app.LoadExcelDataDropDown.Value;
        end

        % Value changed function: VisualizationTypeDropDown
        function VisualizationDropDown(app, event)
            value = app.VisualizationTypeDropDown.Value;

            app.TabGroup.SelectedTab = app.GraphsTab;
            if   app.TabGroup.SelectedTab == app.ResultsTab
                app.TabGroup.SelectedTab = app.GraphsTab;
            end

            if isempty(app.parameters.timePoints) || isempty(app.parameters.realo2Data)
                waitfor(msgbox('Your uploaded data does not include any records for either time or oxygen concentration. Please ensure that you upload the necessary data from the Problem Customization panel before trying again.'));
                return
            else
                t = app.parameters.timePoints(app.data.t_0_i:end);
                Real_O2 = app.parameters.realo2Data(app.data.t_0_i:end);
                Real_OCR = app.parameters.RealOCR;
            end

            % Clear the axes
            clearAxes(app.O2_plot);
            clearAxes(app.OCR_plot);
            clearAxes(app.Cytc_plot);
            clearAxes(app.H_N_plot);
            clearAxes(app.H_P_plot);

            % Clear sketched lines if they exist
            if ~isempty(app.sketchedLines)
                delete(app.sketchedLines);
                app.sketchedLines = [];
            end


            switch value
                case 'Raw Data'
                    % Clear the axes
                    clearAxes(app.O2_plot);
                    clearAxes(app.OCR_plot);
                    clearAxes(app.Cytc_plot);
                    clearAxes(app.H_N_plot);
                    clearAxes(app.H_P_plot);


                    % Plot Oxygen Concentration
                    plot_vis(app.O2_plot, t, Real_O2, app.data.Injection_Times, app.data.Injection_Types, 2, 0.8500, 0.3250, 0.0980, .4, .4, .4, '--','Raw Data')

                    % Plot OCR
                    plot_vis(app.OCR_plot, t(2:end), Real_OCR, app.data.Injection_Times, app.data.Injection_Types, 2, 0.8500, 0.3250, 0.0980, .4, .4, .4, '--','Raw Data')


                case 'Non-Optimized Model'
                    % Clear the axes
                    clearAxes(app.O2_plot);
                    clearAxes(app.OCR_plot);
                    clearAxes(app.Cytc_plot);
                    clearAxes(app.H_N_plot);
                    clearAxes(app.H_P_plot);
                    resetOptimization(app);


                    [t,y] = solver_mito(app.params, app.data, app.Model, app.modeleqns);

                    % Store the values calculated for each variable
                    [cytcred, o2, Hn, Hp] = deal(y(:,1),y(:,2),y(:,3),y(:,4));

                    % Store the intial values calculated for each variable -
                    % Oxygen was calculated when experimental data was
                    % loaded
                    [app.params.init.cytcred, app.params.init.Hn, app.params.init.Hp] = deal(y(1,1),y(1,3),y(1,4));

                    % Calculate the OCR values from the oxygen
                    calcOCR = .5 * ((app.params.fIV_Vmax .* o2) ./ (app.params.fIV_Km .* (1 + (app.params.fIV_K ./ cytcred)) + o2)) .* Hn ./ Hp;
                    calcOCR = calcOCR *1e6;

                    % Plot Oxygen Concentration
                    plot_vis(app.O2_plot, t, [o2, Real_O2], app.data.Injection_Times, app.data.Injection_Types, 2, 0, 0.4470, 0.7410, .4, .4, .4, '--','Model Output','Raw Data')

                    % Plot OCR
                    plot_vis(app.OCR_plot, t(2:end), [calcOCR(2:end), Real_OCR], app.data.Injection_Times, app.data.Injection_Types, 2, 0, 0.4470, 0.7410, .4, .4, .4, '--','Model Output','Raw Data')

                    % Plot Cyt C Reduced
                    plot_vis(app.Cytc_plot, t(2:end), cytcred(2:end), app.data.Injection_Times, app.data.Injection_Types, 2, 0, 0.4470, 0.7410, .4, .4, .4, '--','Model Output')

                    % Plot the Hn concentration over time
                    plot_vis(app.H_N_plot, t(2:end), Hn(2:end), app.data.Injection_Times, app.data.Injection_Types, 2, 0, 0.4470, 0.7410, .4, .4, .4, '--','Model Output')

                    % Plot the Hp concentration over time
                    plot_vis(app.H_P_plot, t(2:end), Hp(2:end), app.data.Injection_Times, app.data.Injection_Types, 2, 0, 0.4470, 0.7410, .4, .4, .4, '--','Model Output')
                    

                    app.OptimizationAlgTypeDropDown.Enable = 'on';
                    app.ButtonGroupPenalty.Enable = 'on';
                    app.ModelSolved = true;

                case 'Optimized Model'
                    if app.ModelOptimized
                        % Clear the axes
                        clearAxes(app.O2_plot);
                        clearAxes(app.OCR_plot);
                        clearAxes(app.Cytc_plot);
                        clearAxes(app.H_N_plot);
                        clearAxes(app.H_P_plot);

                        
                        % Plot Oxygen Concentration
                        plot_vis(app.O2_plot, t, [app.results.O2, Real_O2], app.data.Injection_Times, app.data.Injection_Types, 2, 0, 0.4470, 0.7410, .4, .4, .4, '--', 'Optimized Model Output', 'Raw Data')
                        app.ShowErrorBarCheckBox.Visible = 'on';
                        % Plot OCR
                        plot_vis(app.OCR_plot, t(2:end), [app.results.OCR(2:end), Real_OCR], app.data.Injection_Times, app.data.Injection_Types, 2, 0, 0.4470, 0.7410, .4, .4, .4, '--', 'Optimized Model Output', 'Raw Data')

                        % Plot Cyt C Reduced
                        plot_vis(app.Cytc_plot, t(2:end), app.results.CytCRed(2:end), app.data.Injection_Times, app.data.Injection_Types, 2, 0, 0.4470, 0.7410, .4, .4, .4, '--', 'Optimized Model Output')

                        % Plot Hn concentration over time
                        plot_vis(app.H_N_plot, t(2:end), app.results.Hn(2:end), app.data.Injection_Times, app.data.Injection_Types, 2, 0, 0.4470, 0.7410, .4, .4, .4, '--', 'Optimized Model Output')

                        % Plot Hp concentration over time
                        plot_vis(app.H_P_plot, t(2:end), app.results.Hp(2:end), app.data.Injection_Times, app.data.Injection_Types, 2, 0, 0.4470, 0.7410, .4, .4, .4, '--', 'Optimized Model Output')

                    else
                        waitfor(msgbox('Please optimize the model before selecting this option.'));
                        app.OptimizationAlgTypeDropDown.Enable = 'on';
                        app.ButtonGroupPenalty.Enable = 'on';
                        return
                    end

                case 'In Silico Prediction'
                    % Clear theaxes
                    clearAxes(app.O2_plot);
                    clearAxes(app.OCR_plot);
                    clearAxes(app.Cytc_plot);
                    clearAxes(app.H_N_plot);
                    clearAxes(app.H_P_plot);

                    % Ensure previous results have been loaded
                    if (app.PrevResultsLoaded || app.PrevParamsLoaded)


                        % Define the field names for the first six parameters
                        fieldNames = {'f0_Vmax', 'cytctot', 'f0_Km', 'fIV_Vmax', 'fIV_Km', ...
                            'fIV_K', 'fV_Vmax', 'fV_K', 'fV_Km', 'p_alpha', 'cytcredProp', ...
                            'cytcox', 'cytcred', 'amp_1', 'amp_2', 'amp_3', 'amp_4', 'attenuateProp'};

                        % Retrieve optimized parameters from previously loaded results
                        % Loop through each field name and assign the value
                        if isfield(app.PrevResults.params, 'alphas')
                            numAlphas = numel(fieldnames(app.PrevResults.params.alphas));

                            for i = 1:13
                                app.params.(fieldNames{i}) = app.PrevResults.params.(fieldNames{i});
                            end


                            for ii = 1: numAlphas
                                amp_field = sprintf('amp_%d', ii);
                                app.params.alphas.(fieldNames{13+ii}) = app.PrevResults.params.alphas.(amp_field);

                            end


                            app.params.attenuateProp = app.PrevResults.params.attenuateProp;
                        end

                        % Solve the model using the solver with the parameters saved from previousely optimized results as the initial values for the model
                        [t,y] = solver_mito(app.params, app.data, app.Model, app.modeleqns);

                        % Extract model variables
                        [cytcred, o2, Hn, Hp] = deal(y(:,1),y(:,2),y(:,3),y(:,4));

                        % Calculate OCR from oxygen concentrations
                        calcOCR = .5 * ((app.params.fIV_Vmax .* o2) ./ (app.params.fIV_Km .* (1 + (app.params.fIV_K ./ cytcred)) + o2)) .* Hn ./ Hp;
                        calcOCR = calcOCR * 1e6;  % Scale OCR appropriately

                        % Clear axes
                        clearAxes(app.O2_plot);
                        clearAxes(app.OCR_plot);
                        clearAxes(app.Cytc_plot);
                        clearAxes(app.H_N_plot);
                        clearAxes(app.H_P_plot);

                        % Plot Oxygen Concentration
                        plot_vis(app.O2_plot, t, [o2, Real_O2], app.data.Injection_Times, app.data.Injection_Types, 2, 0.4660, 0.6740, 0.1880, .4, .4, .4, '--', 'In Silico Prediction - O2', 'Raw Data');

                        % Plot OCR
                        plot_vis(app.OCR_plot, t(2:end), [calcOCR(2:end), Real_OCR], app.data.Injection_Times, app.data.Injection_Types, 2, 0.4660, 0.6740, 0.1880, .4, .4, .4, '--', 'In Silico Prediction - OCR', 'Raw Data');

                        % Plot Cyt C Reduced
                        plot_vis(app.Cytc_plot, t(2:end), cytcred(2:end), app.data.Injection_Times, app.data.Injection_Types, 2, 0.4660, 0.6740, 0.1880, .4, .4, .4, '--', 'In Silico Prediction - Cyt C Reduced');

                        % Plot Hn concentration over time
                        plot_vis(app.H_N_plot, t(2:end), Hn(2:end), app.data.Injection_Times, app.data.Injection_Types, 2, 0.4660, 0.6740, 0.1880, .4, .4, .4, '--', 'In Silico Prediction - Hn');

                        % Plot Hp concentration over time
                        plot_vis(app.H_P_plot, t(2:end), Hp(2:end), app.data.Injection_Times, app.data.Injection_Types, 2, 0.4660, 0.6740, 0.1880, .4, .4, .4, '--', 'In Silico Prediction - Hp');

                        % Enable relevant options
                        app.OptimizationAlgTypeDropDown.Enable = 'on';
                        app.ButtonGroupPenalty.Enable = 'on';
                    else

                        waitfor(msgbox('Please load previous results or parameters before running in silico predictions.'));
                        return;

                    end
            end


        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.TotalCytCEditField.Value = 5979.8;
            app.CytCOxidizedEditField.Value = 3901;

            if (app.parameters.oxygen ~= 148.38)
                app.params.oxygen = app.parameters.oxygen;
                app.OxygenEditField.Value = app.params.oxygen;
            else
                app.OxygenEditField.Value = 148.38;
            end

            app.MatrixProtonHsubNsubEditField.Value = 0.015848931924611; % pH = 7.8
            app.IMSProtonHsubPsubEditField.Value = 0.025118864315096; % pH = 7.6

            app.cytctotEditFieldStatus = false;
            app.OxygenEditFieldStatus = false;
            app.cytcoxEditFieldStatus = false;
            app.MatrixProtonHsubNsubEditFieldStatus = false;
            app.IMSProtonHsubPsubEditFieldStatus = false;

            app.params.cytctot = app.TotalCytCEditField.Value;
            app.params.cytcox = app.CytCOxidizedEditField.Value;
            app.params.cytcred = app.params.cytctot - app.params.cytcox;
            app.params.oxygen = app.OxygenEditField.Value;
            app.params.Hn = app.MatrixProtonHsubNsubEditField.Value;
            app.params.pH = -log10(app.params.Hn *1E-6);
            app.params.Hp = app.IMSProtonHsubPsubEditField.Value;

            if app.DataLoaded
                app.OxygenEditField.Value =    app.parameters.oxygen;
            end

        end

        % Menu selected function: ExitMenu, LoadPreviousResultsMenu, 
        % ...and 5 other components
        function fileMenuSelected(app, event)
            % Get the label of the selected menu item
            selectedMenuItem = event.Source.Text;

            % Perform actions based on the selected menu item
            switch selectedMenuItem
                case 'Save Graphs'
                    % Prompt the user to choose a file name and location
                    [file, path, filterIndex] = uiputfile({'*.png'; '*.jpg'; '*.tif'; '*.pdf'; '*.eps'}, 'Save Graphs As');

                    if isequal(file, 0)
                        % User canceled the operation
                        return;
                    end

                    % Create the base file name and path
                    [fileBaseName, ~, ext] = fileparts(fullfile(path, file));

                    % Define the list of static axes to save
                    staticAxesList = {app.O2_plot, app.OCR_plot, app.Cytc_plot, app.H_N_plot, app.H_P_plot};

                    % Save static plots
                    for i = 1:length(staticAxesList)
                        if isvalid(staticAxesList{i}) % Ensure the axes are valid
                            % Construct the file name for each static plot
                            currentFilePath = fullfile(path, sprintf('%s_StaticPlot%d%s', fileBaseName, i, ext));

                            % Save the static plot using exportgraphics
                            exportgraphics(staticAxesList{i}, currentFilePath);
                        end
                    end

                    % Save dynamic plots if they exist
                    if isfield(app, 'DynamicAxes') && ~isempty(app.DynamicAxes)
                        numRows = size(app.DynamicAxes, 1);
                        numCols = size(app.DynamicAxes, 2);

                        for i = 1:numRows
                            for j = 1:numCols
                                if i >= j && isvalid(app.DynamicAxes{i, j}) % Check for valid lower triangle or diagonal axes
                                    % Construct the file name for each dynamic plot
                                    currentFilePath = fullfile(path, sprintf('%s_DynamicPlot_%d_%d%s', fileBaseName, i, j, ext));

                                    % Save the dynamic plot using exportgraphics
                                    exportgraphics(app.DynamicAxes{i, j}, currentFilePath);
                                end
                            end
                        end
                    end

                    % Notify the user that the graphs have been successfully saved
                    msgbox('All graphs have been successfully saved.', 'Save Graphs');

                case 'Save Session'
                    try
                        % Prompt the user to choose a file name and location
                        [file, path] = uiputfile({'*.mat', 'MAT-files (*.mat)'}, 'Save Session As');

                        if isequal(file, 0)
                            % User canceled the operation
                            return;
                        end

                        % Create the full file path
                        fullFilePath = fullfile(path, file);

                        % Gather all app data to save the session
                        sessionData.params = app.params;
                        sessionData.data = app.data;
                        sessionData.results = app.results;
                        sessionData.MCMCResults = app.MCMCResults;
                        sessionData.Model = app.Model;
                        sessionData.modeleqns = app.modeleqns;
                        sessionData.gaInfo = app.gaInfo;
                        sessionData.OptimOutput = app.OptimOutput;

                        % Save dynamic axes (if any)
                        if isfield(app, 'DynamicAxes') && ~isempty(app.DynamicAxes)
                            sessionData.DynamicAxesConfig = getDynamicAxesConfig(app.DynamicAxes);
                        else
                            sessionData.DynamicAxesConfig = [];
                        end

                        % Save GUI states
                        sessionData.GUIState.VisualizationType = app.VisualizationTypeDropDown.Value;
                        sessionData.GUIState.SelectedTab = app.TabGroup.SelectedTab.Title;
                        sessionData.GUIState.SelectedParameters = app.SelectedParameters;
                        sessionData.GUIState.PrevResultsLoaded = app.PrevResultsLoaded;
                        sessionData.GUIState.PrevParamsLoaded = app.PrevParamsLoaded;

                        % Save session to a MAT file
                        save(fullFilePath, 'sessionData');
                        msgbox('Session saved successfully.', 'Save Session');
                    catch ME
                        % Display an error message if something goes wrong
                        msgbox(['Failed to save session: ', ME.message], 'Error', 'error');
                    end

                case 'Load Previous Sessions'
                    try
                        % Prompt the user to select a session file
                        [file, path] = uigetfile({'*.mat', 'MAT-files (*.mat)'}, 'Load Session');

                        if isequal(file, 0)
                            % User canceled the operation
                            return;
                        end

                        % Load the session file
                        fullFilePath = fullfile(path, file);
                        loadedData = load(fullFilePath);

                        if isfield(loadedData, 'sessionData')
                            sessionData = loadedData.sessionData;

                            % Restore app data
                            app.params = sessionData.params;
                            app.data = sessionData.data;
                            app.results = sessionData.results;
                            app.MCMCResults = sessionData.MCMCResults;
                            app.Model = sessionData.Model;
                            app.modeleqns = sessionData.modeleqns;
                            app.gaInfo = sessionData.gaInfo;
                            app.OptimOutput = sessionData.OptimOutput;

                            % Restore dynamic axes (if any)
                            if ~isempty(sessionData.DynamicAxesConfig)
                                restoreDynamicAxes(app, sessionData.DynamicAxesConfig);
                            end

                            % Restore GUI states
                            app.VisualizationTypeDropDown.Value = sessionData.GUIState.VisualizationType;
                            app.TabGroup.SelectedTab = findobj(app.TabGroup.Children, 'Title', sessionData.GUIState.SelectedTab);
                            app.SelectedParameters = sessionData.GUIState.SelectedParameters;
                            app.PrevResultsLoaded = sessionData.GUIState.PrevResultsLoaded;
                            app.PrevParamsLoaded = sessionData.GUIState.PrevParamsLoaded;

                            msgbox('Session loaded successfully.', 'Load Session');
                        else
                            msgbox('The selected file does not contain valid session data.', 'Error', 'error');
                        end
                    catch ME
                        % Display an error message if something goes wrong
                        msgbox(['Failed to load session: ', ME.message], 'Error', 'error');
                    end


                case 'Save Results'
                    try

                        % Define the folder name "Results" in the current directory
                        folderName = fullfile(pwd, 'Results');

                        % Check if the folder exists, if not, create it
                        if ~exist(folderName, 'dir')
                            mkdir(folderName);
                        end
                        % Prompt user to select a file name (without specifying path) for saving the .mat or Excel files
                        [fileName, pathName, filterIndex] = uiputfile({'*.mat', 'MAT-files (*.mat)'; '*.xlsx', 'Excel Files (*.xlsx)'}, 'Save Results', folderName);

                        if isequal(fileName, 0) || isequal(pathName, 0)
                            disp('User canceled the operation.');
                            return;
                        end

                        % Remove any existing extension from the filename
                        [~, name, ext] = fileparts(fileName);
                        if isempty(ext)
                            switch filterIndex
                                case 1
                                    ext = '.mat';
                                case 2
                                    ext = '.xlsx';
                            end
                        end
                        fullFileName = fullfile(pathName, [name, ext]);

                        % Create a single struct containing all the necessary sub-structs from app
                        MitoModelResults.params = app.params;
                        MitoModelResults.data = app.data;
                        MitoModelResults.results = app.results;
                        MitoModelResults.MCMCResults = app.MCMCResults;
                        MitoModelResults.Model = app.Model;
                        MitoModelResults.modeleqns = app.modeleqns;
                        MitoModelResults.gaInfo = app.gaInfo;
                        MitoModelResults.OptimOutput = app.OptimOutput;
                        MitoModelResults.CurrentTimeUnit = app.CurrentTimeUnit;
                        MitoModelResults.CorrMatrixTable = app.CorrMatrixTable;
                        MitoModelResults.heatmap = app.heatmap;

                        % Save based on the selected file type
                        switch filterIndex
                            case 1  % Save as .mat
                                save(fullFileName, 'MitoModelResults');
                                disp(['Results saved to: ', fullFileName]);

                            case 2  % Save as .xlsx
                                % Write each sub-struct to a separate sheet in the Excel file
                                writetable(struct2table(MitoModelResults.params, 'AsArray', true), fullFileName, 'Sheet', 'Params');
                                writetable(struct2table(MitoModelResults.data, 'AsArray', true), fullFileName, 'Sheet', 'Data');
                                writetable(struct2table(MitoModelResults.results, 'AsArray', true), fullFileName, 'Sheet', 'Results');

                                if ~isempty(MitoModelResults.MCMCResults)
                                    writetable(struct2table(MitoModelResults.MCMCResults, 'AsArray', true), fullFileName, 'Sheet', 'MCMCResults');
                                end
                                writetable(struct2table(MitoModelResults.Model, 'AsArray', true), fullFileName, 'Sheet', 'Model');
                                writetable(struct2table(MitoModelResults.modeleqns, 'AsArray', true), fullFileName, 'Sheet', 'ModelEqns');
                                writetable(struct2table(MitoModelResults.gaInfo, 'AsArray', true), fullFileName, 'Sheet', 'GAInfo');
                                writetable(struct2table(MitoModelResults.OptimOutput, 'AsArray', true), fullFileName, 'Sheet', 'OptimOutput');

                                % Save the current time unit in a separate sheet
                                writetable(MitoModelResults.CurrentTimeUnit, fullFileName, 'Sheet', 'CurrentTimeUnit');

                                disp(['Results saved to Excel: ', fullFileName]);
                        end

                    catch ME
                        % Display an error message if something goes wrong
                        msgbox(['Failed to save results: ', ME.message], 'Save Results', 'error');
                    end


                case 'Load Previous Results'
                    try
                        % Define the folder name "Results" in the current directory
                        folderName = fullfile(pwd, 'Results');

                        % Check if the folder exists
                        if ~exist(folderName, 'dir')
                            msgbox('No saved results found. The folder "Results" does not exist.', 'Error', 'error');
                            return;
                        end

                        % Prompt user to select a .mat or .xlsx file to load
                        [fileName, pathName] = uigetfile({'*.mat', 'MAT-files (*.mat)'; '*.xlsx', 'Excel Files (*.xlsx)'}, 'Load Previous Results', folderName);

                        if isequal(fileName, 0) || isequal(pathName, 0)
                            disp('User canceled the operation.');
                            return;
                        end

                        % Get full file path
                        fullFilePath = fullfile(pathName, fileName);

                        % Determine file extension
                        [~, ~, ext] = fileparts(fullFilePath);

                        switch ext
                            case '.mat'
                                % Load the MAT file
                                loadedData = load(fullFilePath);

                                % Check if the file contains the expected variable
                                if isfield(loadedData, 'MitoModelResults')
                                    % Load the struct data back into the app's sub-structs
                                    app.PrevResults.params = loadedData.MitoModelResults.params;
                                    app.PrevResults.data = loadedData.MitoModelResults.data;
                                    app.PrevResults.results = loadedData.MitoModelResults.results;
                                    app.PrevResults.MCMCResults = loadedData.MitoModelResults.MCMCResults;
                                    app.PrevResults.Model = loadedData.MitoModelResults.Model;
                                    app.PrevResults.modeleqns = loadedData.MitoModelResults.modeleqns;
                                    app.PrevResults.gaInfo = loadedData.MitoModelResults.gaInfo;
                                    app.PrevResults.OptimOutput = loadedData.MitoModelResults.OptimOutput;
                                    app.PrevResults.CurrentTimeUnit = loadedData.MitoModelResults.CurrentTimeUnit;
                                    app.PrevResults.CorrMatrixTable = loadedData.MitoModelResults.CorrMatrixTable;
                                    app.PrevResults.heatmap = loadedData.MitoModelResults.heatmap;

                                    app.PrevResultsLoaded = true;
                                    disp(['Loaded results from: ', fullFilePath]);

                                else
                                    msgbox('The selected MAT file does not contain valid results data.', 'Error', 'error');
                                    return;
                                end

                            case '.xlsx'
                                % Load the Excel file and extract the necessary sheets
                                app.PrevResults.params = table2struct(readtable(fullFilePath, 'Sheet', 'Params'));
                                app.PrevResults.data = table2struct(readtable(fullFilePath, 'Sheet', 'Data'));
                                app.PrevResults.results = table2struct(readtable(fullFilePath, 'Sheet', 'Results'));

                                if ismember('MCMCResults', sheetnames(fullFilePath))
                                    app.MCMCResults = table2struct(readtable(fullFilePath, 'Sheet', 'MCMCResults'));
                                else
                                    app.MCMCResults = [];
                                end

                                app.PrevResults.Model = table2struct(readtable(fullFilePath, 'Sheet', 'Model'));
                                app.PrevResults.modeleqns = table2struct(readtable(fullFilePath, 'Sheet', 'ModelEqns'));
                                app.PrevResults.gaInfo = table2struct(readtable(fullFilePath, 'Sheet', 'GAInfo'));
                                app.PrevResults.OptimOutput = table2struct(readtable(fullFilePath, 'Sheet', 'OptimOutput'));
                                app.PrevResults.CurrentTimeUnit = table2array(readtable(fullFilePath, 'Sheet', 'CurrentTimeUnit'));

                                app.PrevResultsLoaded = true;
                                disp(['Loaded results from Excel: ', fullFilePath]);

                            otherwise
                                msgbox('Unsupported file format. Please select a .mat or .xlsx file.', 'Error', 'error');
                                return;
                        end
                        % %
                        % % % Optionally, update any relevant parts of the GUI with the loaded data
                        % % % (e.g., updating tables, plots, etc., based on the loaded results)
                        % % % Example: update results table or UI elements
                        % % updateUIWithLoadedData(app);

                    catch ME
                        % Display an error message if something goes wrong
                        msgbox(['Failed to load results: ', ME.message], 'Load Results', 'error');
                    end


                case 'Load Previously Optimized Parameters'
                    try
                        % Define the folder name "Results" in the current directory
                        folderName = fullfile(pwd, 'OptimizedSolutions');

                        % Check if the folder exists
                        if ~exist(folderName, 'dir')
                            msgbox('No saved results found. The folder "Results" does not exist.', 'Error', 'error');
                            return;
                        end

                        % Prompt user to select a .mat or .xlsx file to load
                        [fileName, pathName] = uigetfile({'*.mat', 'MAT-files (*.mat)'; '*.xlsx', 'Excel Files (*.xlsx)'}, 'Load Previous Results', folderName);

                        if isequal(fileName, 0) || isequal(pathName, 0)
                            disp('User canceled the operation.');
                            return;
                        end

                        % Get full file path
                        fullFilePath = fullfile(pathName, fileName);

                        % Determine file extension
                        [~, ~, ext] = fileparts(fullFilePath);

                        switch ext
                            case '.mat'
                                % Load the MAT file
                                loadedData = load(fullFilePath);

                                % Check if the file contains the expected variable
                                if isfield(loadedData, 'optParams')
                                    % Load the struct data back into the app's sub-structs
                                    % app.PrevResults.params = loadedData.optParams;

                                    % Update parameters with previouly optimized values
                                    app.PrevResults.params.f0_Vmax = loadedData.optParams(1);
                                    app.PrevResults.params.cytctot = loadedData.optParams(2);
                                    app.PrevResults.params.f0_Km = loadedData.optParams(3);
                                    app.PrevResults.params.fIV_Vmax = loadedData.optParams(4);
                                    app.PrevResults.params.fIV_Km = loadedData.optParams(5);
                                    app.PrevResults.params.fIV_K = loadedData.optParams(6);
                                    app.PrevResults.params.fV_Vmax = loadedData.optParams(7);
                                    app.PrevResults.params.fV_K = loadedData.optParams(8);
                                    app.PrevResults.params.fV_Km = loadedData.optParams(9);
                                    app.PrevResults.params.p_alpha = loadedData.optParams(10);
                                    app.PrevResults.params.cytcredProp = loadedData.optParams(11);

                                    app.PrevResults.params.cytcox = loadedData.optParams(2) * loadedData.optParams(11);
                                    app.PrevResults.params.cytcred = app.PrevResults.params.cytctot - app.PrevResults.params.cytcox;


                                    % Dynamically update FCCP-related parameters
                                    if numel(loadedData.optParams)> 11
                                        % Get the number of FCCP injections from app.params.alphas.num_alphas
                                        num_alphas = numel(loadedData.optParams) - 12;

                                        % Update the FCCP injection parameters in app.params.alphas
                                        for i = 1:num_alphas
                                            amp_field = sprintf('amp_%d', i);
                                            app.PrevResults.params.alphas.(amp_field) = loadedData.optParams(11 + i); % Adjusting indices for FCCP params
                                        end

                                        % Set the attenuateProp parameter, following the FCCP params
                                        app.PrevResults.params.attenuateProp = loadedData.optParams(12 + num_alphas);
                                    end

                                    app.PrevParamsLoaded = true;
                                    disp(['Loaded previously optimized parameters from: ', fullFilePath]);

                                else
                                    msgbox('The selected MAT file does not contain valid results data.', 'Error', 'error');
                                    return;
                                end

                            case '.xlsx'
                                % Load the Excel file and extract the necessary sheets
                                app.PrevResults.params = table2struct(readtable(fullFilePath, 'Sheet', 'Params'));

                                app.PrevParamsLoaded = true;
                                disp(['Loaded previously optimized parameters from Excel: ', fullFilePath]);

                            otherwise
                                msgbox('Unsupported file format. Please select a .mat or .xlsx file.', 'Error', 'error');
                                return;
                        end

                    catch ME
                        % Display an error message if something goes wrong
                        msgbox(['Failed to load previously optimized parameters: ', ME.message], 'Load Previously Optimized Parameters', 'error');
                    end


                case 'Exit'
                    % Confirm with the user before closing the application
                    choice = questdlg('Are you sure you want to exit?', 'Exit', 'Yes', 'No', 'No');

                    if strcmp(choice, 'Yes')
                        delete(app.UIFigure);
                    end

            end

        end

        % Menu selected function: AboutMitoModelMenu, FeedbackMenu, 
        % ...and 3 other components
        function helpMenuSelected(app, event)
        % Get the label of the selected menu item
        selectedMenuItem = event.Source.Text;

        % Perform actions based on the selected menu item
        switch selectedMenuItem
                case 'About MitoModel'
                    % Display an informational dialog box about your app
                    dlgTitle = 'About MitoModel';
                    dlgMessage = {'MitoModel is a MATLAB GUI application designed for [provide a brief description of your app].', ...
                        'Version: 1.0', ...
                        'Author: [Your Name]', ...
                        'Copyright © [Year] [Your Name]. All rights reserved.'};
                    uialert(app.UIFigure, dlgMessage, dlgTitle);
                case 'MitoModel Version'
                    % Display version information
                    dlgTitle = 'MitoModel Version Information';
                    dlgMessage = 'MitoModel version 1.0';
                    uialert(app.UIFigure, dlgMessage, dlgTitle);
                    showVersionDialog(app);
                case 'MitoModel Instructions'
                    % Create a new UI figure for displaying instructions
                    instructionFig = uifigure('Name', 'Instructions', 'Position', [100 100 600 400]);

                    % Create a tab group for organizing the content
                    tabGroup = uitabgroup(instructionFig, 'Position', [10 10 200 380]);

                    % Create tabs for different categories of instructions
                    tab1 = uitab(tabGroup, 'Title', 'Getting Started');
                    tab2 = uitab(tabGroup, 'Title', 'Data Preparation');
                    tab3 = uitab(tabGroup, 'Title', 'Advanced Features');
                    tab4 = uitab(tabGroup, 'Title', 'Troubleshooting');

                    % Add instruction text to each tab
                    instructionText1 = uitextarea(tab1, 'Value', { ...
                        'Getting Started:', ...
                        '', ...
                        '1. Step 1: [Provide instruction 1]', ...
                        '2. Step 2: [Provide instruction 2]', ...
                        '3. Step 3: [Provide instruction 3]'}, ...
                        'Editable', false, 'Position', [10 10 180 350]);
                    instructionText2 = uitextarea(tab2, 'Value', { ...
                        'Getting Started:', ...
                        '', ...
                        '1. Step 1: [Provide instruction 1]', ...
                        '2. Step 2: [Provide instruction 2]', ...
                        '3. Step 3: [Provide instruction 3]'}, ...
                        'Editable', false, 'Position', [10 10 180 350]);
                    instructionText3 = uitextarea(tab3, 'Value', { ...
                        'Advanced Features:', ...
                        '', ...
                        '1. Feature 1: [Provide instruction for advanced feature 1]', ...
                        '2. Feature 2: [Provide instruction for advanced feature 2]', ...
                        '3. Feature 3: [Provide instruction for advanced feature 3]'}, ...
                        'Editable', false, 'Position', [10 10 180 350]);

                    instructionText4 = uitextarea(tab4, 'Value', { ...
                        'Troubleshooting:', ...
                        '', ...
                        '1. Issue 1: [Provide troubleshooting instruction for issue 1]', ...
                        '2. Issue 2: [Provide troubleshooting instruction for issue 2]', ...
                        '3. Issue 3: [Provide troubleshooting instruction for issue 3]'}, ...
                        'Editable', false, 'Position', [10 10 180 350]);

                    % Center the instructions window on the screen
                    movegui(instructionFig, 'center');

                    % Make the instructions window visible
                    instructionFig.Visible = 'on';
                case 'Feedback'
                    % Open a feedback form or provide instructions for providing feedback
                    dlgTitle = 'Feedback';
                    dlgMessage = {'We value your feedback!', ...
                        'Please send your comments, suggestions, or bug reports to [your email address] or use the feedback form on our website.'};
                    uialert(app.UIFigure, dlgMessage, dlgTitle);
            end
        end

        % Selection changed function: TimeUnitToggle
        function TimeUnitToggleValueChanged(app, event)
            selectedButton = app.TimeUnitToggle.SelectedObject; % Get the selected toggle button

            if ~isempty(selectedButton)
                selectedText = selectedButton.Text; % Get the text of the selected toggle button
                app.CurrentTimeUnit = selectedText; % Update the current time unit

                % Check the state of the selected button based on its text
                switch selectedText
                    case 'Minute' % If toggle is set to minutes
                        if ~isfield(app.data, 'Time') || isempty(app.data.Time)
                            waitfor(msgbox('Please ensure that you upload the data including a column of times from the Load Data button before trying again.'));
                            return
                        else

                            app.UITable.ColumnName = {'Type'; 'Time (min)'};
                            xlabel(app.O2_plot, 'Time (min)')
                            xlabel(app.OCR_plot, 'Time (min)')
                            xlabel(app.Cytc_plot, 'Time (min)')
                            xlabel(app.H_N_plot, 'Time (min)')
                            xlabel(app.H_P_plot, 'Time (min)')


                            % Convert times from seconds to minutes
                            app.data.Time = app.data.Time ./ 60;
                            app.parameters.timePoints = app.parameters.timePoints ./ 60;
                            app.data.Injection_Times = app.data.Injection_Times ./ 60;

                            % Convert injection times
                            injection_times = {'t_0', 'oligo_t', 'fccp_1_t', 'fccp_2_t', 'fccp_3_t', 'fccp_4_t', 'inhibit_t'};
                            for i = 1:numel(injection_times)
                                app.data.(injection_times{i}) = app.data.(injection_times{i}) ./ 60;
                            end
                        end

                        timepoints_fields = {'baseline_times', 'oligo_times', 'fccp_times', 'inhibit_times'};
                        for i=1:numel(timepoints_fields)
                            app.data.(timepoints_fields{i}) = app.data.(timepoints_fields{i}) ./ 60;
                        end

                        % Update the UITable data
                        injectionTable = table(['Start Time'; app.data.Injection_Types'], [app.parameters.timePoints(app.data.t_0_i); app.data.Injection_Times'], ...
                            'VariableNames', {'Injection Types', 'Time (sec)'});
                        app.UITable.Data = injectionTable;


                    case 'Second' % If toggle is set to seconds
                        if ~isfield(app.data, 'Time') || isempty(app.data.Time)
                            waitfor(msgbox('Please ensure that you upload the data including a column of times from the Load Data button before trying again.'));
                            return
                        else

                            app.UITable.ColumnName = {'Type'; 'Time (sec)'};
                            xlabel(app.O2_plot, 'Time (sec)')
                            xlabel(app.OCR_plot, 'Time (sec)')
                            xlabel(app.Cytc_plot, 'Time (sec)')
                            xlabel(app.H_N_plot, 'Time (sec)')
                            xlabel(app.H_P_plot, 'Time (sec)')


                            % Convert times from seconds to minutes
                            app.data.Time = app.data.Time.*60;
                            app.parameters.timePoints = app.parameters.timePoints.*60;
                            app.data.Injection_Times = app.data.Injection_Times.*60;

                            % Convert injection times
                            injection_times = {'t_0', 'oligo_t', 'fccp_1_t', 'fccp_2_t', 'fccp_3_t', 'fccp_4_t', 'inhibit_t'};
                            for i = 1:numel(injection_times)
                                app.data.(injection_times{i}) = app.data.(injection_times{i}).*60;
                            end

                            timepoints_fields = {'baseline_times', 'oligo_times', 'fccp_times', 'inhibit_times'};
                            for i=1:numel(timepoints_fields)
                                app.data.(timepoints_fields{i}) = app.data.(timepoints_fields{i}).*60;
                            end
                        end

                        % Update the UITable data
                        injectionTable = table(['Start Time'; app.data.Injection_Types'], [app.parameters.timePoints(app.data.t_0_i); app.data.Injection_Times'], ...
                            'VariableNames', {'Injection Types', 'Time (sec)'});
                        app.UITable.Data = injectionTable;
                end
            end
        end

        % Value changed function: ResultsTypeDropDown
        function ResultsTypeDropDownValueChanged(app, event)
            value = app.ResultsTypeDropDown.Value;
            app.mcmcInfoPanel.Visible = 'off';
            optimizedParams = num2cell(app.OptimOutput.Params');
            

            % Define base parameter names
            app.results.parameterNames = {
                '<div style="padding: 30px 0;"><i>V<sub>max<sub>c<sub>0</sub></sub></i></div>', ...
                '<div style="padding: 30px 0;"><i>cyt c<sub>tot</sub></i></div>', ...
                '<div style="padding: 30px 0;"><i>K<sub>m<sub>c<sub>0</sub></sub></i></div>', ...
                '<div style="padding: 30px 0;"><i>V<sub>max<sub>c<sub>IV</sub></sub></i></div>', ...
                '<div style="padding: 30px 0;"><i>K<sub>m<sub>c<sub>IV</sub></sub></i></div>', ...
                '<div style="padding: 30px 0;"><i>K<sub>c<sub>IV</sub></sub></i></div>', ...
                '<div style="padding: 30px 0;"><i>V<sub>max<sub>c<sub>V</sub></sub></i></div>', ...
                '<div style="padding: 30px 0;"><i>K<sub>c<sub>V</sub></sub></i></div>', ...
                '<div style="padding: 30px 0;"><i>K<sub>m<sub>c<sub>V</sub></sub></i></div>', ...
                '<div style="padding: 30px 0;"><i>P<sub>leak</sub></i></div>', ...
                '<div style="padding: 30px 0;"><i>cyt c red<sub>Prop</sub></i></div>'
                };

            % Define parameter names for labeling
            app.results.parameterNames_LaTeX = {
                '$V_{max_{c_0}}$', ...
                '$Cyt c_{tot}$', ...
                '$K_{m_{c_0}}$', ...
                '$V_{max_{c_{IV}}}$', ...
                '$K_{m_{c_{IV}}}$', ...
                '$K_{c_{IV}}$', ...
                '$V_{max_{c_V}}$', ...
                '$K_{c_V}$', ...
                '$K_{m_{c_V}}$', ...
                '$P_{leak}$', ...
                '$Cyt c_{red_{Prop}}$'
                };


            % Dynamically append alpha parameters if the model type is FullModelGlobal
            if strcmp(app.Model.Type, 'FullModelGlobal')
                % Get the number of alphas dynamically
                num_alphas = app.params.alphas.num_alphas;

                % Generate alpha parameter names
                alphaNamesHTML = arrayfun(@(i) sprintf('<div style="padding: 30px 0;"><i>&#x0251<sub>%d</sub></i></div>', i), 1:num_alphas, 'UniformOutput', false);
                alphaNamesLaTeX = arrayfun(@(i) sprintf('$\\alpha_%d$', i), 1:num_alphas, 'UniformOutput', false);

                % Add alpha parameter names to the results lists
                app.results.parameterNames = [app.results.parameterNames, alphaNamesHTML];
                app.results.parameterNames_LaTeX = [app.results.parameterNames_LaTeX, alphaNamesLaTeX];

                % Add the attenuateProp parameter
                app.results.parameterNames{end+1} = '<div style="padding: 30px 0;"><i>r<sub>attenuate</sub></i></div>';
                app.results.parameterNames_LaTeX{end+1} = '$r_{attenuate}$';
            end

            parameterNames = app.results.parameterNames;
            parameterNames_LaTeX = app.results.parameterNames_LaTeX;


            % Check the selected value and display the corresponding results
            switch value
                case 'Display'
                    app.TabGroup.SelectedTab = app.ResultsTab;
                    app.OptimizedParametersPanel.Visible = 'on';
                    app.GAInfo.Visible = 'on';
                    % Clear previous results
                    if ~isempty(app.ResultsTable.Data)
                        app.ResultsTable.Data= {};
                    end
                    if ~isempty(app.GAInfoTable.Data)
                        app.GAInfoTable.Data = {};
                    end

                    % Prepare data for the table
                    tableData = cell(length(parameterNames), 2);
                    for i = 1:length(parameterNames)
                        tableData{i, 1} = parameterNames{i};
                        tableData{i, 2} = optimizedParams{i};
                    end

                    % Update the data in ResultsTable
                    app.ResultsTable.Data = tableData;
                    app.ResultsTable.ColumnName = {'Parameter', 'Optimized Value'};
                    app.ResultsTable.ColumnEditable = [false false];

                    % Create and apply the HTML interpreter style
                    htmlStyle = uistyle('Interpreter', 'html');
                    addStyle(app.ResultsTable, htmlStyle);


                    % Update GA info in the table
                    gaInfoData = {
                        'Optimization Success', app.gaInfo.successInfo;
                        'Number of Iterations', app.gaInfo.numIterations;
                        'Population Size', app.gaInfo.PopulationSize;
                        'Max Generations', app.gaInfo.MaxGenerations;
                        'GA Error', app.gaInfo.gaError;
                        'Duration (s)', app.gaInfo.Duration
                        };


                    if isfield(app.gaInfo, 'bootstrapSamples')
                        gaInfoData = [gaInfoData; {'Bootstrap Samples', app.gaInfo.bootstrapSamples}];
                    end


                    % Update the data in GAInfoTable
                    app.GAInfoTable.Data = gaInfoData;

                    % Create and apply the HTML interpreter style
                    htmlStyle = uistyle('Interpreter', 'html');
                    addStyle(app.GAInfoTable, htmlStyle);

                case 'Markov Chain Monte Carlo (MCMC) Analysis'
                    if isempty(app.ResultsTable.Data)
                        msgbox('Please first choose Display to fill the required records, then choose this item.');
                        return;
                    end
                    app.TabGroup.SelectedTab = app.ResultsTab;

                    if ~isempty(app.mcmcInfoTable.Data)
                        app.mcmcInfoTable.Data = {};
                    end

                    % Prompt user for MCMC parameters
                    prompt = {'Enter the number of MCMC iterations:', ...
                        'Enter the thinning factor:', ...
                        'Enter the step size for proposal distribution:', ...
                        'Enter the confidence level (%):'};
                    dlgtitle = 'MCMC Parameters';
                    dims = [1 35];
                    definput = {'1000', '10', '0.1', '95'};
                    answer = inputdlg(prompt, dlgtitle, dims, definput);

                    if isempty(answer)
                        return; % User canceled the dialog
                    end

                    % Parse user inputs
                    mccount = str2double(answer{1});
                    skip = str2double(answer{2});
                    stepSize = str2double(answer{3});
                    confidenceLevel = str2double(answer{4}) / 100;

                    % Run MCMC and store results in app.MCMCResults
                    runMCMC(app, mccount, skip, stepSize, confidenceLevel);

                    % Retrieve MCMC results
                    mcmcResults = app.MCMCResults;
                    meanParams = num2cell(mcmcResults.meanParams);
                    confidenceIntervals = mcmcResults.confidenceIntervals;


                    % Prepare data for the Results Table
                    tableData = cell(length(parameterNames), 4);
                    for i = 1:length(parameterNames)
                        tableData{i, 1} = parameterNames{i};
                        tableData{i, 2} = optimizedParams{i};
                        tableData{i, 3} = meanParams{i};
                        tableData{i, 4} = sprintf('[%.5f, %.5f]', confidenceIntervals(i, :));
                    end

                    % Make mcmcInfoTable visible
                    app.mcmcInfoPanel.Visible = 'on';

                    % Update the data in MCMC Results Table
                    app.ResultsTable.Data = tableData;
                    app.ResultsTable.ColumnName = {'Parameter', 'Optimized Value', 'Mean Value', 'Confidence Interval (CI)'};
                    app.ResultsTable.ColumnEditable = [false false false false];

                    % Create and apply the HTML interpreter style
                    htmlStyle = uistyle('Interpreter', 'html');
                    addStyle(app.ResultsTable, htmlStyle);

                    % Display log-prior and log-likelihood in mcmcInfoTable
                    finalLogPrior = mcmcResults.logP(end, 1);
                    finalLogLikelihood = mcmcResults.logP(end, 2);

                    % Update mcmcInfoTable
                    infoTableData = {
                        'Final Log-Prior', finalLogPrior;
                        'Final Log-Likelihood', finalLogLikelihood;
                        'Overall Acceptance Rate', mcmcResults.overallAcceptanceRate;
                        'Log-Prior Acceptance Rate', mcmcResults.acceptanceRate; % Assuming this is correct
                        'Log-Likelihood Acceptance Rate', mcmcResults.acceptanceRate; % Needs confirmation if this is correct
                        'Average Log-Prior', mcmcResults.avgLogPrior;
                        'Average Log-Likelihood', mcmcResults.avgLogLikelihood;
                        };

                    if ~isempty(app.GAInfoTable.Data)
                        app.mcmcInfoTable.Data = infoTableData;
                    end

                    % Create and apply HTML interpreter style for mcmcInfoTable
                    addStyle(app.mcmcInfoTable, htmlStyle);


                case 'Bootstrap Confidence Intervals'
                    app.TabGroup.SelectedTab = app.ResultsTab;
                    % Make mcmcInfoTable visible
                    if ~isempty(app.mcmcInfoTable.Data)
                        app.mcmcInfoPanel.Visible = 'on';
                    end

                    if strcmp(app.OptimizationAlgTypeDropDown.Value, 'Enhanced GA (Bootstrap)') && isfield(app.OptimOutput, 'confIntervals') && ~isempty(app.OptimOutput.confIntervals)
                        % Get confidence interval values
                        CI = app.OptimOutput.confIntervals';
                        lowerCI = CI(:,1);
                        upperCI = CI(:,2);

                        % Prepare the data for the table by adding each confidence interval in a new row
                        CI_String = [lowerCI, upperCI];

                        if isfield(app.MCMCResults, 'confidenceIntervals') && ~isempty(app.ResultsTable.Data)
                            % Prepare data for the Results Table
                            tableData = cell(length(parameterNames), 5);
                            for i = 1:length(parameterNames)
                                tableData{i, 1} = app.ResultsTable.Data{i, 1};
                                tableData{i, 2} = app.ResultsTable.Data{i, 2};
                                tableData{i, 3} = app.ResultsTable.Data{i, 3};
                                tableData{i, 4} = app.ResultsTable.Data{i, 4};
                                tableData{i, 5} = sprintf('[%.5f, %.5f]', CI_String(i, :));
                            end
                            app.ResultsTable.ColumnName = {'Parameter', 'Optimized Value', 'Mean Value', 'Confidence Interval (CI)', 'CI by Bootstrap Samples'};
                            app.ResultsTable.Data =  tableData;
                        else
                            % Prepare data for the Results Table
                            tableData = cell(length(parameterNames), 3);
                            for i = 1:length(parameterNames)
                                tableData{i, 1} = parameterNames{i};
                                tableData{i, 2} = optimizedParams{i};
                                tableData{i, 3} = sprintf('[%.5f, %.5f]', CI_String(i, :));

                                % Update the data in MCMC Results Table
                                app.ResultsTable.Data = tableData;
                                app.ResultsTable.ColumnName = {'Parameter', 'Optimized Value', 'Confidence Interval  by Bootstrap Samples'};
                                app.ResultsTable.ColumnEditable = [false false false false];
                            end
                        end
                    else

                        msgbox('Bootstrap Confidence Intervals are only available if the model was optimized using the Enhanced GA (Bootstrap) method. Please optimize the model with this method and try again.');
                    end

                case 'Correlation Analysis'
                    % Switch to the "Correlation Analysis" tab
                    app.TabGroup.SelectedTab = app.CorrelationAnalysisTab;


                    % Clear previous results
                    if ~isempty(app.CorrMatrixTable.Data)
                        app.CorrMatrixTable.Data = {};
                        app.CorrMatrixTable.ColumnName = {};
                    end

                    % Retrieve MCMC samples
                    samples = app.MCMCResults.samples;
                    numParams = size(samples, 2);


                    % Compute the correlation matrix
                    correlationMatrix = corr(samples);

                    % Retrieve user-defined limits for filtering the correlation matrix
                    lowerLimit = app.GreaterthanandequaltoEditField.Value;
                    upperLimit = app.LessthanandequaltoEditField.Value;


                    % Apply the filtering criteria to the correlation matrix
                    filteredCorrelationMatrix = correlationMatrix;
                    filteredCorrelationMatrix(correlationMatrix < lowerLimit | correlationMatrix > upperLimit) = NaN;


                    % Fill the first row and first column with bold parameter names
                    for i = 1:length(parameterNames)
                        % Wrap the parameter name with HTML tags for bold text
                        formattedParameter = ['<html><span style="font-size:14px;"><b>', parameterNames{i}, '</b></span></html>'];

                        % Adjusted to fill starting from the second column in the first row
                        app.CorrMatrixTable.Data{1, i + 1} = formattedParameter;

                        % Adjusted to fill starting from the second row in the first column
                        app.CorrMatrixTable.Data{i + 1, 1} = formattedParameter;
                    end

                    % Fill the rest of the table with the filtered correlation matrix values
                    for i = 1:length(parameterNames)
                        for j = 1:length(parameterNames)
                            if isnan(filteredCorrelationMatrix(i, j))
                                app.CorrMatrixTable.Data{i + 1, j + 1} = ''; % Leave blank if the value doesn't meet criteria
                            else
                                app.CorrMatrixTable.Data{i + 1, j + 1} = filteredCorrelationMatrix(i, j); % Adjusted to start from the second row and column
                            end
                        end
                    end


                    % Create and apply the HTML interpreter style
                    htmlStyle = uistyle('Interpreter', 'html');
                    addStyle(app.CorrMatrixTable, htmlStyle);

                    % Clear any existing heatmap in the panel or tab
                    if isfield(app, 'heatmap')
                        delete(app.heatmap);
                    end

                    % Define and apply custom colormap
                    customColormap = createCustomColormap();

                    % Create the heatmap directly in the desired tab or panel
                    app.heatmap = heatmap(app.CorrelationAnalysisTab, filteredCorrelationMatrix, 'Colormap', customColormap, 'ColorLimits', [-1, 1], 'Interpreter', 'latex');

                    % Customize the appearance
                    if lowerLimit== -1 && upperLimit == 1
                        app.heatmap.Title = 'Correlation Matrix Heatmap';
                    else
                        app.heatmap.Title = 'Filtered Correlation Matrix Heatmap';
                    end
                    app.heatmap.XDisplayLabels = parameterNames_LaTeX; % Use the same parameter names
                    app.heatmap.YDisplayLabels = parameterNames_LaTeX; % Use the same parameter names
                    app.heatmap.XLabel = 'Parameters';
                    app.heatmap.YLabel = 'Parameters';
                    app.heatmap.CellLabelColor = 'none'; % Disable default cell labels

                    % Adjust heatmap properties
                    app.heatmap.FontSize = 14;

                    app.PreviousButton.Visible = 'on';
                    app.NextButton.Visible = 'on';

                    % Define the path for saving the figures
                    resultsFolder = 'Reports';
                    figuresFolder = fullfile(resultsFolder, 'Figures');

                    % Create the Figures folder if it does not exist
                    if ~exist(figuresFolder, 'dir')
                        mkdir(figuresFolder);
                    end

                    % Save the heatmap figure in the results folder
                    heatmapFileName = fullfile(figuresFolder, 'heatmap.png');
                    % Save the heatmap figure using exportgraphics
                    exportgraphics(app.heatmap, heatmapFileName, 'Resolution', 300);

                case 'Error Analysis'
                    % Switch to the "Error Analysis" tab
                    app.TabGroup.SelectedTab = app.ErrorAnalysisTab;

                    % Reset visibility of panels and buttons
                    app.ParameterselectionPanel.Visible = 'on';
                    app.GridLayout2.Visible = 'on';
                    app.PlotErrorAnalysisButton.Visible = 'on';


                    % Clear the SelectedParameters and DynamicAxes
                    if ~isempty(app.SelectedParameters)
                        % Reset the selected parameters in the app
                        app.SelectedParameters = {};
                    end

                    % Define parameter names and tags for each checkbox
                    checkboxTags = {'V_Max_C0', 'Cyt_c_tot', 'K_m_c0', 'V_Max_C_IV', ...
                        'K_m_C_IV', 'K_C_IV', 'V_Max_C_V', 'K_C_V', ...
                        'K_m_C_V', 'P_leak', 'Cyt_c_red_0', 'alpha_1', ...
                        'alpha_2', 'alpha_3', 'alpha_4', 'r_attenuate'};

                    % Get the number of alpha parameters to include
                    num_alphas = app.params.alphas.num_alphas;

                    % Deactivate and uncheck checkboxes based on optimized parameters
                    for i = 1:length(checkboxTags)
                        checkboxTag = checkboxTags{i};
                        if isprop(app, sprintf('CheckBox_%s', checkboxTag))
                            checkbox = app.(sprintf('CheckBox_%s', checkboxTag));

                            % Uncheck all checkboxes
                            checkbox.Value = 0;

                            % Deactivate alpha checkboxes that exceed num_alphas
                            if startsWith(checkboxTag, 'alpha_')
                                alphaIndex = str2double(erase(checkboxTag, 'alpha_'));
                                if alphaIndex > num_alphas
                                    checkbox.Enable = 'off';
                                else
                                    checkbox.Enable = 'on';
                                end
                            else
                                % Enable non-alpha checkboxes
                                checkbox.Enable = 'on';
                            end
                        end
                    end


                    % Delete all existing axes in the Error Analysis tab
                    if isfield(app, 'DynamicAxes') && ~isempty(app.DynamicAxes)
                        for i = 1:numel(app.DynamicAxes)
                            if isvalid(app.DynamicAxes{i})  % Check if the axis handle is valid
                                delete(app.DynamicAxes{i});  % Delete the axis
                            end
                        end
                    end

                    if ~isempty(app.DynamicAxes)
                        app.DynamicAxes = {};
                    end

                    % Additionally, selectively delete only axes in the ErrorAnalysisTab
                    axChildren = findall(app.ErrorAnalysisTab, 'Type', 'axes');  % Find all axes in the tab
                    delete(axChildren);  % Delete only axes, preserving other UI components

                    % Adjust the visibility of labels based on the model type and num_alphas
                    if strcmp(app.Model.Type, 'FullModelGlobal') 
                        % Show labels for FullModelGlobal based on num_alphas
                        for i = 1:4
                            labelName = sprintf('Label_%d_alpha_%d', 11 + i, i);  % Dynamically create label names
                            if isprop(app, labelName)
                                if i <= num_alphas
                                    app.(labelName).Visible = 'on';
                                else
                                    app.(labelName).Visible = 'off';
                                end
                            end
                        end
                        % Show r_attenuate label for FullModelGlobal
                        app.Label_16_r_attenuate.Visible = 'on';

                    elseif strcmp(app.Model.Type, 'Baseline Model') 
                        % Hide all alpha labels for Baseline Model
                        app.Label_12_alpha_1.Visible = 'off';
                        app.Label_13_alpha_2.Visible = 'off';
                        app.Label_14_alpha_3.Visible = 'off';
                        app.Label_15_alpha_4.Visible = 'off';
                        % Hide r_attenuate label for Baseline Model
                        app.Label_16_r_attenuate.Visible = 'off';
                    end

                case 'Download Report'
                    % Ask the user which type of report they want to generate
                    reportType = questdlg('Select report type:', 'Report Type', 'PDF', 'HTML', 'HTML');
                    % reportType = questdlg('Select report type:', 'Report Type', 'PDF', 'HTML', 'Excel', 'Excel');
                    % Define the folder name where the HTML results will be saved
                    folderName = 'Reports';

                    switch reportType
                        case 'PDF'
                            savePDFReport(app, folderName);

                        case 'HTML'
                            % Call the function to generate and save the HTML report
                            saveHTMLReport(app, folderName);
                            % case 'Excel'
                            %     % Call the function to generate and save the Excel report
                            %     saveExcelReport(app, folderName);
                    end

            end

        end

        % Callback function
        function ButtonGroupWeightSelectionChanged(app, event)
            selectedButton = app.ButtonGroupWeight.SelectedObject;
            selectedButton = event.NewWeightValue;
            if strcmp(selectedButton, 'Unweighted')
                app.WeightStatus  = 0;
            else
                app.WeightStatus = 1;
            end
        end

        % Selection changed function: ButtonGroupPenalty
        function ButtonGroupPenaltySelectionChanged(app, event)
            selectedButton = app.ButtonGroupPenalty.SelectedObject.Text;

            if strcmp(selectedButton, 'Disable Penalty')
                app.ModelWeight = ones(size(app.data.Injection_Types));
            else
                app.Model.PenaltyType = 'Enable Penalty';
                app.UIWeightTable.Enable = 'on';

                % Populate table with injection types and empty weights
                injectionTypes = [ 'ADP', app.data.Injection_Types];
                numInjections = numel(injectionTypes');

                % Create cell array for table data
                tableData = cell(numInjections, 2);

                % Populate first column with injection types and set default weights to 1
                for i = 1:numInjections
                    tableData{i, 1} = injectionTypes{i};
                    tableData{i, 2} = 1;  % Default weight
                end

                % Assign data to the table, leaving weights column empty initially
                app.UIWeightTable.Data = tableData;

                % Allow user to edit the second column (weights)
                app.UIWeightTable.ColumnEditable = [false true];
            end
        end

        % Display data changed function: UIWeightTable
        function UIWeightTableDisplayDataChanged(app, event)
            newDisplayData = app.UIWeightTable.Data;

            % Update ModelWeight based on the edited weights in the table
            numInjections = size(newDisplayData, 1);
            newWeights = ones(numInjections, 1);  % Default weights

            for i = 1:numInjections
                newWeights(i) = newDisplayData{i, 2};
            end

            app.Model.Weight = newWeights;

        end

        % Button pushed function: NextButton, PreviousButton
        function navigateCorrelationAnalysis(app, event)
            % Determine the direction based on which button was clicked
            direction = event.Source.Text;

            switch app.currentView
                case 'matrix'
                    if strcmp(direction, 'Previous')
                        % Switch to the heatmap view
                        app.TabGroup.SelectedTab = app.CorrelationAnalysisTab;

                        % Show the heatmap and hide the matrix table
                        app.heatmap.Visible = 'on';    % Make the heatmap visible
                        app.CorrMatrixTable.Visible = 'off'; % Hide the table
                        app.CorrelationMatrixPanel.Visible = 'off';
                        app.CorrTabTextbox.Visible  = 'off';
                        app.LessthanandequaltoEditField.Visible = 'off';
                        app.LessthanandequaltoEditFieldLabel.Visible = 'off';
                        app.GreaterthanandequaltoEditField.Visible = 'off';
                        app.GreaterthanandequaltoEditFieldLabel.Visible = 'off';
                        app.currentView = 'heatmap';    % Update current view

                        % Optionally, adjust button visibility or state here
                        app.PreviousButton.Enable = 'off';  % Enable the 'Previous' button
                        app.NextButton.Enable = 'on'; % Disable the 'Next' button when in heatmap view
                    end
                case 'heatmap'
                    if strcmp(direction, 'Next')
                        % Switch back to the matrix view
                        app.TabGroup.SelectedTab = app.CorrelationAnalysisTab;

                        % Update the uitable with correlation matrix data
                        app.CorrMatrixTable.Visible = 'on'; % Make the table visible
                        app.CorrelationMatrixPanel.Visible = 'on';
                        app.CorrTabTextbox.Visible = 'on';
                        app.LessthanandequaltoEditField.Visible = 'on';
                        app.LessthanandequaltoEditFieldLabel.Visible = 'on';
                        app.GreaterthanandequaltoEditField.Visible = 'on';
                        app.GreaterthanandequaltoEditFieldLabel.Visible = 'on';
                        app.heatmap.Visible = 'off';   % Hide the heatmap
                        app.currentView = 'matrix';    % Update current view

                        % Optionally, adjust button visibility or state here
                        app.PreviousButton.Enable = 'on'; % Disable the 'Previous' button when in matrix view
                        app.NextButton.Enable = 'off';  % Enable the 'Next' button
                    end
            end
        end

        % Value changed function: CheckBox_Cyt_c_red_prop, 
        % ...and 15 other components
        function CheckboxValueChanged(app, event)
            % Get the source of the event (i.e., the checkbox that triggered the callback)
            checkbox = event.Source;

            % Define parameter names and tags for each checkbox
            checkboxTags = {'V_Max_C0', 'Cyt_c_tot', 'K_m_c0', 'V_Max_C_IV', ...
                'K_m_C_IV', 'K_C_IV', 'V_Max_C_V', 'K_C_V', ...
                'K_m_C_V', 'P_leak', 'Cyt_c_red_prop'};
            checkboxTags_alphas = {'alpha_1', 'alpha_2', 'alpha_3', 'alpha_4'};
            % Get the number of alpha parameters to include
            num_alphas = app.params.alphas.num_alphas;

            checkboxTags = [checkboxTags, checkboxTags_alphas(1:num_alphas), 'r_attenuate'];

            % Create a map for checkboxes and parameters
            parameterNames = app.results.parameterNames_LaTeX;

            checkboxMap = containers.Map(checkboxTags, parameterNames);

            % Initialize checkedBoxes as an empty cell array
            checkedBoxes = {};

            % Count the currently checked boxes
            totalChecked = 0;
            for i = 1:length(checkboxTags)
                if app.(sprintf('CheckBox_%s', checkboxTags{i})).Value
                    totalChecked = totalChecked + 1;
                end
            end

            % Check if the current selection would exceed the limit
            if totalChecked > 4
                % Uncheck the checkbox that triggered the callback
                checkbox.Value = 0;

                % Show a warning dialog to the user
                uialert(app.UIFigure, 'You can select up to 4 parameters only.', 'Selection Limit');
                return;
            end

            % If within limit, update the list of checked boxes
            for i = 1:length(checkboxTags)
                if app.(sprintf('CheckBox_%s', checkboxTags{i})).Value
                    % Append the corresponding value to checkedBoxes
                    checkedBoxes{end+1} = checkboxMap(checkboxTags{i});
                end
            end

            % Update the selected parameters in the app
            app.SelectedParameters = checkedBoxes;

            % Handle checkbox visibility based on model type
            if strcmp(app.Model.Type, 'FullModelGlobal')
                % Make the last 5 checkboxes visible
                for i = 12:numel(checkboxTags)
                    app.(sprintf('CheckBox_%s', checkboxTags{i})).Visible = 'on';
                end
            else
                % Hide the last 5 checkboxes
                for i = 12:numel(checkboxTags)
                    app.(sprintf('CheckBox_%s', checkboxTags{i})).Visible = 'off';
                end
            end

            % Update plot button visibility
            app.PlotErrorAnalysisButton.Visible = 'on';

        end

        % Value changed function: LessthanandequaltoEditField
        function LessthanandequaltoEditFieldValueChanged(app, event)
            % Get the new value from the Less than edit field
            upperLimit = app.LessthanandequaltoEditField.Value;

            % Get the current value from the Greater than edit field
            lowerLimit = app.GreaterthanandequaltoEditField.Value;

            % Check if the upper limit is a real number between -1 and 1
            if ~isreal(upperLimit) || upperLimit < -1 || upperLimit > 1
                uialert(app.UIFigure, 'Please enter a real number between -1 and 1.', 'Invalid Input');
                return;
            end

            % Ensure the lower limit is less than the upper limit
            if upperLimit <= lowerLimit
                uialert(app.UIFigure, 'This value must be greater than the value appearing in the box on the left.', 'Invalid Range');
                return;
            end


            % Reapply the filtering criteria and update the correlation matrix
            updateCorrelationMatrix(app, lowerLimit, upperLimit);

        end

        % Value changed function: GreaterthanandequaltoEditField
        function GreaterthanandequaltoEditFieldValueChanged(app, event)
            % Get the new value from the Greater than edit field
            lowerLimit = app.GreaterthanandequaltoEditField.Value;

            % Get the current value from the Less than edit field
            upperLimit = app.LessthanandequaltoEditField.Value;

            % Check if the lower limit is a real number between -1 and 1
            if ~isreal(lowerLimit) || lowerLimit < -1 || lowerLimit > 1
                uialert(app.UIFigure, 'Please enter a real number between -1 and 1.', 'Invalid Input');
                return;
            end

            % Ensure the lower limit is less than the upper limit
            if lowerLimit >= upperLimit
                uialert(app.UIFigure, 'This value must be less than the value appearing in the box on the right side.', 'Invalid Range');
                return;
            end


            % Reapply the filtering criteria and update the correlation matrix
            updateCorrelationMatrix(app, lowerLimit, upperLimit);

        end

        % Button pushed function: PlotErrorAnalysisButton
        function PlotErrorAnalysisButtonPushed(app, event)
            % Get the selected parameters
            selectedParams = app.SelectedParameters;
            numSelected = length(selectedParams);

            % Update objects visibility
            app.ParameterselectionPanel.Visible = 'off';
            app.GridLayout2.Visible = 'off';
            app.PlotErrorAnalysisButton.Visible = 'off';
            app.BackButton.Visible = 'on';

            if numSelected == 0
                uialert(app.UIFigure, 'No parameters selected for plotting.', 'No Selection');
                return;
            end

            % Clear previously created axes if they exist
            if isfield(app, 'DynamicAxes') && ~isempty(app.DynamicAxes)
                for i = 1:numel(app.DynamicAxes)
                    if isvalid(app.DynamicAxes{i})
                        delete(app.DynamicAxes{i});
                    end
                end
                app.DynamicAxes = {}; % Clear the array
            end

            % Retrieve MCMC samples
            samples = app.MCMCResults.samples;
            logP = app.MCMCResults.logP; % Access logP values
            logLikelihood = logP(:, 2); % Extract the log-likelihood values
            numParams = size(samples, 2);


            % Find the indices of the selected parameters
            paramIndices = [];
            parameterNames = app.results.parameterNames_LaTeX;

            for i = 1:numParams
                if ismember(parameterNames{i}, selectedParams)
                    paramIndices = [paramIndices, i];
                end
            end

            % Define the number of rows and columns for the axes grid
            numRows = numSelected;
            numCols = numSelected;
            app.DynamicAxes = cell(numRows, numCols);  % Initialize axes handles

            % Get the size of the ErrorAnalysisTab in pixels
            tabPosition = app.ErrorAnalysisTab.Position;
            tabWidth = tabPosition(3);
            tabHeight = tabPosition(4);

            % Calculate axWidth and axHeight based on the number of columns and rows
            axWidth = tabWidth / numCols - 5;
            axHeight = tabHeight / numRows;


            % Define the path for saving the figures
            resultsFolder = 'Reports';
            figuresFolder = fullfile(resultsFolder, 'Figures');

            % Create the Figures folder if it does not exist
            if ~exist(figuresFolder, 'dir')
                mkdir(figuresFolder);
            end


            % Create subplots dynamically and plot for selected parameters
            for i = 1:numRows
                for j = 1:numCols
                    % Check if the current position is in the lower triangle or diagonal
                    if i >= j
                        % Calculate position for each subplot in the Error Analysis tab
                        axPosition = [(j-1) * axWidth, tabHeight - i * axHeight, axWidth, axHeight];
                        ax = uiaxes(app.ErrorAnalysisTab, 'Position', axPosition); % Create axes on the Error Analysis tab
                        app.DynamicAxes{i, j} = ax;  % Store axes handle

                        cla(ax, 'reset'); % Clear the content of each axis

                        if i == j
                            % Diagonal axes for histograms
                            histogram(ax, samples(:, paramIndices(i)), 'FaceColor', [0 0.4470 0.7410], 'EdgeColor', 'k', 'FaceAlpha', 0.7, 'LineWidth', 0.8);
                            xlabel(ax, parameterNames{paramIndices(i)}, 'Interpreter', 'latex', 'FontSize', 12, 'FontWeight', 'bold');
                            ylabel(ax, 'Frequency', 'FontSize', 12, 'FontWeight','bold');
                            title(ax, sprintf('Histogram of %s', parameterNames{paramIndices(i)}), 'Interpreter', 'latex', 'FontSize', 14, 'FontWeight', 'bold');
                        elseif i > j
                            % Lower triangular part for scatter plots with log-likelihood as color
                            scatter(ax, samples(:, paramIndices(j)), samples(:, paramIndices(i)), 50, logLikelihood, 'filled');
                            xlabel(ax, parameterNames{paramIndices(j)}, 'Interpreter', 'latex', 'FontSize', 12, 'FontWeight', 'bold');
                            ylabel(ax, selectedParams{i}, 'Interpreter', 'latex', 'FontSize', 12, 'FontWeight', 'bold');
                            title(ax, sprintf('Scatter of %s vs %s', parameterNames{paramIndices(i)}, parameterNames{paramIndices(j)}), 'Interpreter', 'latex', 'FontSize', 14, 'FontWeight', 'bold');
                            % Add colorbar to indicate log-likelihood values
                            cb = colorbar(ax);
                            cb.Label.String = 'Log-Likelihood';
                        end

                        % % Customize axis appearance
                        % ax.XColor = [0.5 0.5 0.5];
                        % ax.YColor = [0.5 0.5 0.5];
                        ax.XTick = linspace(min(samples(:, paramIndices(j))), max(samples(:, paramIndices(j))), 5);
                        % ax.YTick = linspace(min(samples(:, paramIndices(i))), max(samples(:, paramIndices(i))), 5);
                        grid(ax, 'on');

                        % % Save the figure
                        % % Create a new figure to save the plot
                        % fig = figure('Visible', 'off');
                        % copyobj(app.DynamicAxes{i, j}, fig); % Copy the axes to the new figure
                        % % Save the figures in the Figures folder
                        % FigName = fullfile(figuresFolder, sprintf('Plot_%d_%d.png', i, j));
                        % % Save the figure using exportgraphics
                        % exportgraphics(fig, FigName, 'Resolution', 300);
                        % close(fig); % Close the figure to free memory
                    end
                end
            end
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            % Set the dropdown to 'Error Analysis' and trigger the callback
            app.ResultsTypeDropDown.Value = 'Error Analysis';
            app.BackButton.Visible = 'off';
            ResultsTypeDropDownValueChanged(app, struct('Value', 'Error Analysis'));
        end

        % Value changed function: ShowErrorBarCheckBox
        function ShowErrorBarCheckBoxValueChanged(app, event)
            % Get the checkbox value
            showErrorBars = event.Source;

            if showErrorBars.Value
                plot_vis(app.O2_plot, app.data.Time, [app.results.O2,  app.parameters.RealO2Data], app.data.Injection_Times, app.data.Injection_Types, 2, 0, 0.4470, 0.7410, .4, .4, .4, '--', 'Optimized Model Output', 'Raw Data')
                hold on;
                % Plot with error bars if checkbox is checked
                errorbar(app.O2_plot, app.data.Time, app.results.O2, app.data.errors, 'o-', 'Color', [0.4470, 0.7410, 0.8], 'LineWidth', 0.5, 'MarkerSize', 4, 'DisplayName', 'Optimized Model Output');
            else
                clearAxes(app.O2_plot);
                plot_vis(app.O2_plot, app.data.Time, [app.results.O2,  app.parameters.RealO2Data], app.data.Injection_Times, app.data.Injection_Types, 2, 0, 0.4470, 0.7410, .4, .4, .4, '--', 'Optimized Model Output', 'Raw Data')
            end
            hold off;

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {707, 707};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {484, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.file = uimenu(app.UIFigure);
            app.file.Text = 'File';
            app.Menu = uimenu(app.file);
            app.Menu.Text = 'Menu';
            app.SaveGraphsMenu = uimenu(app.file);
            app.SaveGraphsMenu.MenuSelectedFcn = createCallbackFcn(app, @fileMenuSelected, true);
            app.SaveGraphsMenu.Accelerator = 'G';
            app.SaveGraphsMenu.Text = 'Save Graphs';
            app.SaveSessionMenu = uimenu(app.file);
            app.SaveSessionMenu.MenuSelectedFcn = createCallbackFcn(app, @fileMenuSelected, true);
            app.SaveSessionMenu.Separator = 'on';
            app.SaveSessionMenu.Accelerator = 'S';
            app.SaveSessionMenu.Text = 'Save Session';
            app.LoadPreviousSessionsMenu = uimenu(app.file);
            app.LoadPreviousSessionsMenu.MenuSelectedFcn = createCallbackFcn(app, @fileMenuSelected, true);
            app.LoadPreviousSessionsMenu.Accelerator = 'L';
            app.LoadPreviousSessionsMenu.Text = 'Load Previous Sessions';
            app.SaveResultsMenu = uimenu(app.file);
            app.SaveResultsMenu.MenuSelectedFcn = createCallbackFcn(app, @fileMenuSelected, true);
            app.SaveResultsMenu.Separator = 'on';
            app.SaveResultsMenu.Accelerator = 'R';
            app.SaveResultsMenu.Text = 'Save Results';
            app.LoadPreviousResultsMenu = uimenu(app.file);
            app.LoadPreviousResultsMenu.MenuSelectedFcn = createCallbackFcn(app, @fileMenuSelected, true);
            app.LoadPreviousResultsMenu.Text = 'Load Previous Results';
            app.LoadPreviouslyOptimizedParametersMenu = uimenu(app.file);
            app.LoadPreviouslyOptimizedParametersMenu.MenuSelectedFcn = createCallbackFcn(app, @fileMenuSelected, true);
            app.LoadPreviouslyOptimizedParametersMenu.Text = 'Load Previously Optimized Parameters';
            app.ExitMenu = uimenu(app.file);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @fileMenuSelected, true);
            app.ExitMenu.Separator = 'on';
            app.ExitMenu.Accelerator = 'W';
            app.ExitMenu.Text = 'Exit';
            app.help = uimenu(app.UIFigure);
            app.help.MenuSelectedFcn = createCallbackFcn(app, @helpMenuSelected, true);
            app.help.Text = 'Help';
            app.AboutMitoModelMenu = uimenu(app.help);
            app.AboutMitoModelMenu.MenuSelectedFcn = createCallbackFcn(app, @helpMenuSelected, true);
            app.AboutMitoModelMenu.Text = 'About MitoModel';
            app.MitoModelVersionMenu = uimenu(app.help);
            app.MitoModelVersionMenu.MenuSelectedFcn = createCallbackFcn(app, @helpMenuSelected, true);
            app.MitoModelVersionMenu.Text = 'MitoModel Version';
            app.MitoModelInstructionsMenu = uimenu(app.help);
            app.MitoModelInstructionsMenu.MenuSelectedFcn = createCallbackFcn(app, @helpMenuSelected, true);
            app.MitoModelInstructionsMenu.Text = 'MitoModel Instructions';
            app.FeedbackMenu = uimenu(app.help);
            app.FeedbackMenu.MenuSelectedFcn = createCallbackFcn(app, @helpMenuSelected, true);
            app.FeedbackMenu.Text = 'Feedback';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ModelSelectorPanel = uipanel(app.LeftPanel);
            app.ModelSelectorPanel.Title = 'Model Selector';
            app.ButtonGroupModel = uibuttongroup(app.ModelSelectorPanel);
            app.ButtonGroupModel.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupModelSelectionChanged, true);
            app.BaselineModelButton = uiradiobutton(app.ButtonGroupModel);
            app.BaselineModelButton.Text = 'Baseline Model';
            app.BaselineModelButton.Value = true;
            app.FullModelGlobalButton = uiradiobutton(app.ButtonGroupModel);
            app.FullModelGlobalButton.Text = 'Full Model - Global';
            app.ButtonGroupPenalty = uibuttongroup(app.ModelSelectorPanel);
            app.ButtonGroupPenalty.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupPenaltySelectionChanged, true);
            app.UnweightedButton = uiradiobutton(app.ButtonGroupPenalty);
            app.UnweightedButton.Text = 'Unweighted';
            app.UnweightedButton.Value = true;
            app.WeightedButton = uiradiobutton(app.ButtonGroupPenalty);
            app.WeightedButton.Text = 'Weighted';
            app.UIWeightTable = uitable(app.ModelSelectorPanel);
            app.UIWeightTable.ColumnName = {'Interval'; 'Weight'};
            app.UIWeightTable.RowName = {};
            app.UIWeightTable.DisplayDataChangedFcn = createCallbackFcn(app, @UIWeightTableDisplayDataChanged, true);
            app.Problem_Customization = uipanel(app.LeftPanel);
            app.UITable = uitable(app.Problem_Customization);
            app.UITable.ColumnName = {'Injection'; 'Time (s)'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.EditTableButton = uibutton(app.Problem_Customization, 'push');
            app.EditTableButton.ButtonPushedFcn = createCallbackFcn(app, @EditTableButtonPushed, true);
            app.EditTableButton.Text = 'Edit Table';
            app.LoadExcelDataDropDown = uidropdown(app.Problem_Customization);
            app.LoadExcelDataDropDown.Items = {'Select File...'};
            app.LoadExcelDataDropDown.ValueChangedFcn = createCallbackFcn(app, @ExcelDataLoadButtonPushed, true);
            app.LoadExcelDataDropDown.Enable = 'off';
            app.LoadExcelDataDropDown.Value = 'Select File...';
            app.ExcelDataLoadButton = uibutton(app.Problem_Customization, 'push');
            app.ExcelDataLoadButton.ButtonPushedFcn = createCallbackFcn(app, @ExcelDataLoadButtonPushed, true);
            app.ExcelDataLoadButton.Icon = 'file_open.png';
            app.ExcelDataLoadButton.Text = '';
            app.SimulationtimesecEditFieldLabel_2 = uilabel(app.Problem_Customization);
            app.SimulationtimesecEditFieldLabel_2.Text = 'Load Data';
            app.TimeUnitToggle = uibuttongroup(app.Problem_Customization);
            app.TimeUnitToggle.SelectionChangedFcn = createCallbackFcn(app, @TimeUnitToggleValueChanged, true);
            app.TimeUnitToggle.Title = 'Time Unit';
            app.SecondButton = uitogglebutton(app.TimeUnitToggle);
            app.SecondButton.Text = 'Second';
            app.SecondButton.Value = true;
            app.MinuteButton = uitogglebutton(app.TimeUnitToggle);
            app.MinuteButton.Text = 'Minute';
            app.ParameterInitializationPanel = uipanel(app.LeftPanel);
            app.CytCOxidizedEditFieldLabel = uilabel(app.ParameterInitializationPanel);
            app.CytCOxidizedEditFieldLabel.Text = 'Cyt C Oxidized';
            app.CytCOxidizedEditField = uieditfield(app.ParameterInitializationPanel, 'numeric');
            app.CytCOxidizedEditField.ValueChangedFcn = createCallbackFcn(app, @CytCOxidizedEditFieldValueChanged, true);
            app.CytCOxidizedEditField.Tooltip = {'(nmol/mL)'};
            app.CytCOxidizedEditField.Value = 100;
            app.OxygenEditFieldLabel = uilabel(app.ParameterInitializationPanel);
            app.OxygenEditFieldLabel.Text = 'Oxygen ';
            app.OxygenEditField = uieditfield(app.ParameterInitializationPanel, 'numeric');
            app.OxygenEditField.ValueChangedFcn = createCallbackFcn(app, @OxygenEditFieldValueChanged, true);
            app.OxygenEditField.Tooltip = {'(nmol/mL)'};
            app.OxygenEditField.Value = 180;
            app.MatrixProtonH_nLabel = uilabel(app.ParameterInitializationPanel);
            app.MatrixProtonH_nLabel.Interpreter = 'html';
            app.MatrixProtonH_nLabel.Text = 'Matrix Proton (H<sub>N</sub>)';
            app.MatrixProtonHsubNsubEditField = uieditfield(app.ParameterInitializationPanel, 'numeric');
            app.MatrixProtonHsubNsubEditField.ValueChangedFcn = createCallbackFcn(app, @MatrixProtonHsubNsubEditFieldValueChanged, true);
            app.MatrixProtonHsubNsubEditField.Tooltip = {'(nmol/mL)'};
            app.MatrixProtonHsubNsubEditField.Value = 0.015848931924611;
            app.IMSProtonHsubPsubEditFieldLabel = uilabel(app.ParameterInitializationPanel);
            app.IMSProtonHsubPsubEditFieldLabel.Interpreter = 'html';
            app.IMSProtonHsubPsubEditFieldLabel.Text = 'IMS Proton (H<sub>P</sub>)';
            app.IMSProtonHsubPsubEditField = uieditfield(app.ParameterInitializationPanel, 'numeric');
            app.IMSProtonHsubPsubEditField.ValueChangedFcn = createCallbackFcn(app, @IMSProtonHsubPsubEditFieldValueChanged, true);
            app.IMSProtonHsubPsubEditField.Tooltip = {'(nmol/mL)'};
            app.IMSProtonHsubPsubEditField.Value = 0.025118864315096;
            app.ResetButton = uibutton(app.ParameterInitializationPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.TotalCytCEditFieldLabel = uilabel(app.ParameterInitializationPanel);
            app.TotalCytCEditFieldLabel.Text = 'Total Cyt C ';
            app.TotalCytCEditField = uieditfield(app.ParameterInitializationPanel, 'numeric');
            app.TotalCytCEditField.ValueDisplayFormat = '%11.4g ';
            app.TotalCytCEditField.ValueChangedFcn = createCallbackFcn(app, @TotalCytCEditFieldValueChanged, true);
            app.TotalCytCEditField.Tooltip = {'(nmol/mL)'};
            app.TotalCytCEditField.Value = 250;
            app.ParameterInitializationLabel = uilabel(app.LeftPanel);
            app.ParameterInitializationLabel.Text = 'Parameter Initialization';
            app.DataAnalysisHubPanel = uipanel(app.LeftPanel);
            app.DataAnalysisHubPanel.Title = 'Data Analysis Hub';
            app.OptimizationPanel = uipanel(app.DataAnalysisHubPanel);
            app.OptimizationAlgTypeDropDown = uidropdown(app.OptimizationPanel);
            app.OptimizationAlgTypeDropDown.Items = {'Select an item', 'Genetic Algorithm', 'Enhanced GA (Bootstrap)', 'Enhanced GA (Hybrid Function)', 'Batch GA Optimization'};
            app.OptimizationAlgTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @OptimizationDropDown, true);
            app.OptimizationAlgTypeDropDown.Value = 'Select an item';
            app.ResultsPanel = uipanel(app.DataAnalysisHubPanel);
            app.ResultsTypeDropDown = uidropdown(app.ResultsPanel);
            app.ResultsTypeDropDown.Items = {'Select an item', 'Display', 'Markov Chain Monte Carlo (MCMC) Analysis', 'Bootstrap Confidence Intervals', 'Correlation Analysis', 'Error Analysis', 'Download Report'};
            app.ResultsTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ResultsTypeDropDownValueChanged, true);
            app.ResultsTypeDropDown.Value = 'Select an item';
            app.OptimizationLabel = uilabel(app.DataAnalysisHubPanel);
            app.OptimizationLabel.Text = 'Optimization';
            app.ResultsLabel = uilabel(app.DataAnalysisHubPanel);
            app.ResultsLabel.Text = 'Results';
            app.VisualizationPanel = uipanel(app.DataAnalysisHubPanel);
            app.VisualizationTypeDropDown = uidropdown(app.VisualizationPanel);
            app.VisualizationTypeDropDown.Items = {'Select an item', 'Raw Data', 'Non-Optimized Model', 'Optimized Model', 'In Silico Prediction'};
            app.VisualizationTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @VisualizationDropDown, true);
            app.VisualizationTypeDropDown.Value = 'Select an item';
            app.VisualizationLabel = uilabel(app.DataAnalysisHubPanel);
            app.VisualizationLabel.Text = 'Visualization';
            app.ProblemCustomizationLabel = uilabel(app.LeftPanel);
            app.ProblemCustomizationLabel.Text = 'Problem Customization';
            app.MainControlLabel = uilabel(app.LeftPanel);
            app.MainControlLabel.Text = 'Main Control';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.GraphsTab = uitab(app.TabGroup);
            app.GraphsTab.Title = 'Graphs';
            app.Cytc_plot = uiaxes(app.GraphsTab);
            title(app.Cytc_plot, 'Cyt c Reduced Concentration')
            xlabel(app.Cytc_plot, 'Time (sec)')
            ylabel(app.Cytc_plot, 'Cyt C (nmol/ mL)')
            zlabel(app.Cytc_plot, 'Z')
            app.O2_plot = uiaxes(app.GraphsTab);
            title(app.O2_plot, 'Oxygen Concentration')
            xlabel(app.O2_plot, 'Time (sec)')
            ylabel(app.O2_plot, 'O_2 (nmol / mL)')
            zlabel(app.O2_plot, 'Z')
            app.OCR_plot = uiaxes(app.GraphsTab);
            title(app.OCR_plot, 'OCR')
            xlabel(app.OCR_plot, 'Time (sec)')
            ylabel(app.OCR_plot, 'OCR (pmol/ (mL*sec))')
            zlabel(app.OCR_plot, 'Z')
            app.H_N_plot = uiaxes(app.GraphsTab);
            title(app.H_N_plot, 'Matrix Proton Concentration')
            xlabel(app.H_N_plot, 'Time (sec)')
            ylabel(app.H_N_plot, 'H_N ( nmol/ mL)')
            zlabel(app.H_N_plot, 'Z')
            app.H_P_plot = uiaxes(app.GraphsTab);
            title(app.H_P_plot, 'IMS Proton Concentration')
            xlabel(app.H_P_plot, 'Time (sec)')
            ylabel(app.H_P_plot, 'H_P (nmol/ mL)')
            zlabel(app.H_P_plot, 'Z')
            app.MathematicalModelforMitochondrialFunctioningLabel = uilabel(app.GraphsTab);
            app.MathematicalModelforMitochondrialFunctioningLabel.Text = 'Mathematical Model for Mitochondrial Functioning';
            app.ShowErrorBarCheckBox = uicheckbox(app.GraphsTab);
            app.ShowErrorBarCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowErrorBarCheckBoxValueChanged, true);
            app.ShowErrorBarCheckBox.Text = 'Show Error Bar';
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.OptimizedParametersPanel = uipanel(app.ResultsTab);
            app.OptimizedParametersPanel.Title = 'Optimized Parameters';
            app.ResultsTable = uitable(app.OptimizedParametersPanel);
            app.ResultsTable.ColumnName = {'Parameter'; 'Optimized Value'};
            app.ResultsTable.RowName = {};
            app.GAInfo = uipanel(app.ResultsTab);
            app.GAInfo.Title = 'GA Execution Details';
            app.GAInfoTable = uitable(app.GAInfo);
            app.GAInfoTable.ColumnName = '';
            app.GAInfoTable.RowName = {};
            app.mcmcInfoPanel = uipanel(app.ResultsTab);
            app.mcmcInfoPanel.Title = 'MCMC Details';
            app.mcmcInfoTable = uitable(app.mcmcInfoPanel);
            app.mcmcInfoTable.ColumnName = '';
            app.mcmcInfoTable.RowName = {};
            app.CorrelationAnalysisTab = uitab(app.TabGroup);
            app.CorrelationAnalysisTab.Title = 'Correlation Analysis';
            app.PreviousButton = uibutton(app.CorrelationAnalysisTab, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @navigateCorrelationAnalysis, true);
            app.PreviousButton.Text = 'Previous';
            app.NextButton = uibutton(app.CorrelationAnalysisTab, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @navigateCorrelationAnalysis, true);
            app.NextButton.Text = 'Next';
            app.CorrelationMatrixPanel = uipanel(app.CorrelationAnalysisTab);
            app.CorrelationMatrixPanel.Title = 'Correlation Matrix';
            app.CorrMatrixTable = uitable(app.CorrelationAnalysisTab);
            app.CorrMatrixTable.ColumnName = '';
            app.CorrMatrixTable.RowName = {};
            app.GreaterthanandequaltoEditFieldLabel = uilabel(app.CorrelationAnalysisTab);
            app.GreaterthanandequaltoEditFieldLabel.Text = 'Greater than and equal to';
            app.GreaterthanandequaltoEditField = uieditfield(app.CorrelationAnalysisTab, 'numeric');
            app.GreaterthanandequaltoEditField.ValueDisplayFormat = '%11.4g ';
            app.GreaterthanandequaltoEditField.ValueChangedFcn = createCallbackFcn(app, @GreaterthanandequaltoEditFieldValueChanged, true);
            app.GreaterthanandequaltoEditField.Tooltip = {'Enter a value between -1 and 1'};
            app.GreaterthanandequaltoEditField.Value = -1;
            app.CorrTabTextbox = uilabel(app.CorrelationAnalysisTab);
            app.CorrTabTextbox.Text = 'Display Correlation Values:';
            app.LessthanandequaltoEditFieldLabel = uilabel(app.CorrelationAnalysisTab);
            app.LessthanandequaltoEditFieldLabel.Text = 'Less than and equal to';
            app.LessthanandequaltoEditField = uieditfield(app.CorrelationAnalysisTab, 'numeric');
            app.LessthanandequaltoEditField.ValueDisplayFormat = '%11.4g ';
            app.LessthanandequaltoEditField.ValueChangedFcn = createCallbackFcn(app, @LessthanandequaltoEditFieldValueChanged, true);
            app.LessthanandequaltoEditField.Tooltip = {'Enter a value between -1 and 1'};
            app.LessthanandequaltoEditField.Value = 1;
            app.ErrorAnalysisTab = uitab(app.TabGroup);
            app.ErrorAnalysisTab.Title = 'Error Analysis';
            app.ParameterselectionPanel = uipanel(app.ErrorAnalysisTab);
            app.ParameterselectionPanel.Title = 'Parameter selection';
            app.GridLayout2 = uigridlayout(app.ParameterselectionPanel);
            app.CheckBox_V_Max_C0 = uicheckbox(app.GridLayout2);
            app.CheckBox_V_Max_C0.ValueChangedFcn = createCallbackFcn(app, @CheckboxValueChanged, true);
            app.CheckBox_V_Max_C0.Text = '';
            app.Label_1_V_Max_C0 = uilabel(app.GridLayout2);
            app.Label_1_V_Max_C0.Interpreter = 'html';
            app.Label_1_V_Max_C0.Text = '<div style="padding: 30px 0;"><i>V<sub>max<sub>c<sub>0</sub></i></div>';
            app.Label_0 = uilabel(app.GridLayout2);
            app.Label_0.Text = 'Choose up to Four Parameters to Plot:';
            app.CheckBox_Cyt_c_tot = uicheckbox(app.GridLayout2);
            app.CheckBox_Cyt_c_tot.ValueChangedFcn = createCallbackFcn(app, @CheckboxValueChanged, true);
            app.CheckBox_Cyt_c_tot.Text = '';
            app.Label_2_Cyt_c_tot = uilabel(app.GridLayout2);
            app.Label_2_Cyt_c_tot.Interpreter = 'html';
            app.Label_2_Cyt_c_tot.Text = '<div style="padding: 30px 0;"><i>cyt c<sub>tot</i></div>';
            app.CheckBox_K_m_c0 = uicheckbox(app.GridLayout2);
            app.CheckBox_K_m_c0.ValueChangedFcn = createCallbackFcn(app, @CheckboxValueChanged, true);
            app.CheckBox_K_m_c0.Text = '';
            app.Label_3_K_m_c0 = uilabel(app.GridLayout2);
            app.Label_3_K_m_c0.Interpreter = 'html';
            app.Label_3_K_m_c0.Text = '<div style="padding: 30px 0;"><i>K<sub>m<sub>c<sub>0</sub></i></div>';
            app.CheckBox_K_m_C_IV = uicheckbox(app.GridLayout2);
            app.CheckBox_K_m_C_IV.ValueChangedFcn = createCallbackFcn(app, @CheckboxValueChanged, true);
            app.CheckBox_K_m_C_IV.Text = '';
            app.Label_4_V_Max_C_IV = uilabel(app.GridLayout2);
            app.Label_4_V_Max_C_IV.Interpreter = 'html';
            app.Label_4_V_Max_C_IV.Text = '<div style="padding: 30px 0;"><i>V<sub>max<sub>c<sub>IV</sub></i></div>';
            app.Label_5_K_m_C_IV = uilabel(app.GridLayout2);
            app.Label_5_K_m_C_IV.Interpreter = 'html';
            app.Label_5_K_m_C_IV.Text = '<div style="padding: 30px 0;"><i>K<sub>m<sub>c<sub>IV</sub></i></div>';
            app.CheckBox_K_C_IV = uicheckbox(app.GridLayout2);
            app.CheckBox_K_C_IV.ValueChangedFcn = createCallbackFcn(app, @CheckboxValueChanged, true);
            app.CheckBox_K_C_IV.Text = '';
            app.Label_6_K_C_IV = uilabel(app.GridLayout2);
            app.Label_6_K_C_IV.Interpreter = 'html';
            app.Label_6_K_C_IV.Text = '<div style="padding: 30px 0;"><i>K<sub>c<sub>IV</sub></i></div>';
            app.CheckBox_r_attenuate = uicheckbox(app.GridLayout2);
            app.CheckBox_r_attenuate.ValueChangedFcn = createCallbackFcn(app, @CheckboxValueChanged, true);
            app.CheckBox_r_attenuate.Text = '';
            app.Label_7_CheckBox_V_Max_C_V = uilabel(app.GridLayout2);
            app.Label_7_CheckBox_V_Max_C_V.Interpreter = 'html';
            app.Label_7_CheckBox_V_Max_C_V.Text = '<div style="padding: 30px 0;"><i>V<sub>max<sub>c<sub>V</sub></i></div>';
            app.CheckBox_alpha_4 = uicheckbox(app.GridLayout2);
            app.CheckBox_alpha_4.ValueChangedFcn = createCallbackFcn(app, @CheckboxValueChanged, true);
            app.CheckBox_alpha_4.Text = '';
            app.Label_8_K_C_V = uilabel(app.GridLayout2);
            app.Label_8_K_C_V.Interpreter = 'html';
            app.Label_8_K_C_V.Text = '<div style="padding: 30px 0;"><i>K<sub>c<sub>V</sub></i></div>';
            app.CheckBox_alpha_3 = uicheckbox(app.GridLayout2);
            app.CheckBox_alpha_3.ValueChangedFcn = createCallbackFcn(app, @CheckboxValueChanged, true);
            app.CheckBox_alpha_3.Text = '';
            app.Label_9_K_m_C_V = uilabel(app.GridLayout2);
            app.Label_9_K_m_C_V.Interpreter = 'html';
            app.Label_9_K_m_C_V.Text = '<div style="padding: 30px 0;"><i>K<sub>m<sub>c<sub>V</sub></i></div>';
            app.CheckBox_alpha_1 = uicheckbox(app.GridLayout2);
            app.CheckBox_alpha_1.ValueChangedFcn = createCallbackFcn(app, @CheckboxValueChanged, true);
            app.CheckBox_alpha_1.Text = '';
            app.Label_10_P_leak = uilabel(app.GridLayout2);
            app.Label_10_P_leak.Interpreter = 'html';
            app.Label_10_P_leak.Text = '<div style="padding: 30px 0;"><i>P<sub>leak</i></div>';
            app.CheckBox_alpha_2 = uicheckbox(app.GridLayout2);
            app.CheckBox_alpha_2.ValueChangedFcn = createCallbackFcn(app, @CheckboxValueChanged, true);
            app.CheckBox_alpha_2.Text = '';
            app.Label_11_Cyt_c_red_prop = uilabel(app.GridLayout2);
            app.Label_11_Cyt_c_red_prop.Interpreter = 'html';
            app.Label_11_Cyt_c_red_prop.Text = '<div style="padding: 30px 0;"><i>cyt c red<sub>Prop</i></div>';
            app.CheckBox_V_Max_C_IV = uicheckbox(app.GridLayout2);
            app.CheckBox_V_Max_C_IV.ValueChangedFcn = createCallbackFcn(app, @CheckboxValueChanged, true);
            app.CheckBox_V_Max_C_IV.Text = '';
            app.Label_12_alpha_1 = uilabel(app.GridLayout2);
            app.Label_12_alpha_1.Interpreter = 'html';
            app.Label_12_alpha_1.Text = '<div style="padding: 30px 0;"><i>&#x0251<sub>1</i></div>';
            app.CheckBox_K_m_C_V = uicheckbox(app.GridLayout2);
            app.CheckBox_K_m_C_V.ValueChangedFcn = createCallbackFcn(app, @CheckboxValueChanged, true);
            app.CheckBox_K_m_C_V.Text = '';
            app.Label_13_alpha_2 = uilabel(app.GridLayout2);
            app.Label_13_alpha_2.Interpreter = 'html';
            app.Label_13_alpha_2.Text = '<div style="padding: 30px 0;"><i>&#x0251<sub>2</i></div>';
            app.CheckBox_K_C_V = uicheckbox(app.GridLayout2);
            app.CheckBox_K_C_V.ValueChangedFcn = createCallbackFcn(app, @CheckboxValueChanged, true);
            app.CheckBox_K_C_V.Text = '';
            app.Label_14_alpha_3 = uilabel(app.GridLayout2);
            app.Label_14_alpha_3.Interpreter = 'html';
            app.Label_14_alpha_3.Text = '<div style="padding: 30px 0;"><i>&#x0251<sub>3</i></div>';
            app.Label_15_alpha_4 = uilabel(app.GridLayout2);
            app.Label_15_alpha_4.Interpreter = 'html';
            app.Label_15_alpha_4.Text = '<div style="padding: 30px 0;"><i>&#x0251<sub>4</i></div>';
            app.CheckBox_V_Max_C_V = uicheckbox(app.GridLayout2);
            app.CheckBox_V_Max_C_V.ValueChangedFcn = createCallbackFcn(app, @CheckboxValueChanged, true);
            app.CheckBox_V_Max_C_V.Text = '';
            app.Label_16_r_attenuate = uilabel(app.GridLayout2);
            app.Label_16_r_attenuate.Interpreter = 'html';
            app.Label_16_r_attenuate.Text = '<div style="padding: 30px 0;"><i>r<sub>attenuate</i></div>';
            app.CheckBox_P_leak = uicheckbox(app.GridLayout2);
            app.CheckBox_P_leak.ValueChangedFcn = createCallbackFcn(app, @CheckboxValueChanged, true);
            app.CheckBox_P_leak.Text = '';
            app.CheckBox_Cyt_c_red_prop = uicheckbox(app.GridLayout2);
            app.CheckBox_Cyt_c_red_prop.ValueChangedFcn = createCallbackFcn(app, @CheckboxValueChanged, true);
            app.CheckBox_Cyt_c_red_prop.Text = '';
            app.PlotErrorAnalysisButton = uibutton(app.GridLayout2, 'push');
            app.PlotErrorAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @PlotErrorAnalysisButtonPushed, true);
            app.PlotErrorAnalysisButton.Text = 'Plot Error Analysis';
            app.BackButton = uibutton(app.ErrorAnalysisTab, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MitoModel_V1_May_2024

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end